classdef deConV_MATLAB_GUI_peakshift < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ResetPlotButton                 matlab.ui.control.Button
        ClearPlotButton_2               matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        LoadDataTab                     matlab.ui.container.Tab
        UpperVLimitEditField            matlab.ui.control.NumericEditField
        UpperVLimitEditFieldLabel       matlab.ui.control.Label
        LowerVLimitEditField            matlab.ui.control.NumericEditField
        LowerVLimitEditFieldLabel       matlab.ui.control.Label
        LoadFormattedDataButton         matlab.ui.control.Button
        FilepathEditField               matlab.ui.control.EditField
        BrowseDataButton                matlab.ui.control.Button
        FormatDataTab                   matlab.ui.container.Tab
        OpenDataFormatterButton         matlab.ui.control.Button
        RateTable                       matlab.ui.control.Table
        PlotontoratemVsDropDown         matlab.ui.control.DropDown
        PlotontoratemVsDropDownLabel    matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        Switch                          matlab.ui.control.Switch
        PlotontoratemVsDropDownLabel_2  matlab.ui.control.Label
        PeakpositioncorrectionButton    matlab.ui.control.Button
        DeconvoluteCurrentButton        matlab.ui.control.Button
        SaveEditField                   matlab.ui.control.EditField
        SaveDropDown                    matlab.ui.control.DropDown
        GetTableButton                  matlab.ui.control.Button
        SaveFigureButton                matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        TT_in
        fxR
        fxO
        red
        ox
        V
        rate

        total_list
        
        redox_mask
        
        pseudo
        capacit
        farad
        fx
        
        hf
        
        h1
        x1
        p1
        xvals

        E_red_all
        E_ox_all
        hasCal % peak shifted? (0 = False, 1 = True)
        stackedArea
    end

    %#function robustfit
    %#function mad
    %#function findpeaks

    methods (Access = private)
        
        function [data, voltage_col, current_col, nc_col, Ns_changes_col, time_col] = ECLabread(app, filename)
            try
                data = readtable(filename,'PreserveVariableNames',true);
            catch ME
                warning(strcat('The following file could not be found: ',filename));
                set(app.FilepathEditField,'Value','Input Correct File Path')
                return
            end
            header=data.Properties.VariableNames;
            data = table2array(data);
            [voltage_col, current_col, nc_col, Ns_changes_col, time_col] = ECLabHeaderRead(app, header);
        end

        function [voltage_col, current_col, nc_col, Ns_changes_col, time_col] = ECLabHeaderRead(app, header)
            voltage_col = contains(header,'Ewe');
            current_col = contains(header,'<I>/mA');
            nc_col = contains(header,'cycle number');
            Ns_changes_col = contains(header,'Ns changes');
            time_col = contains(header,'time/s');
        end

        function [red, ox, rate] = CVspline(app, x, y, V, time)

            xmin = find(x==min(x),1);
            xmax = find(x==max(x),1);
            if xmin>xmax
                tempmax = xmax;
                xmax = xmin;
                xmin = tempmax;
                redoxflip = 1;
            else
                redoxflip = 0;
            end
            
            [xtopunique,jj] = unique(x(xmin:xmax));
            ytop=y(xmin:xmax);
            ytopunique = ytop(jj);
            
            xbottom = [x(xmin-1:-1:1); x(end:-1:xmax+1)];
            ybottom = [y(xmin-1:-1:1); y(end:-1:xmax+1)];
            
            [xbottomunique, i] = unique(xbottom);
            ybottomunique = ybottom(i);
            
            timetop = time(xmin:xmin+length(xtopunique)-1);
            
            rate = round((xtopunique(end)-xtopunique(2))/(timetop(end)-timetop(2))*1000,2,'significant');
            
            if redoxflip == 0
                red = interp1(xbottomunique,ybottomunique,V);
                ox = interp1(xtopunique,ytopunique,V);
            elseif redoxflip == 1
                ox = interp1(xbottomunique,ybottomunique,V);
                red = interp1(xtopunique,ytopunique,V);
            end
                
        end
        
        function axis_formatting(app, ax)
            ax.Units = 'normalized';
            % ax.Position = [0.05 0.15 0.9 0.9];
            % ax.PlotBoxAspectRatio = [1,0.808266360505166,0.808266360505166];
            ax.OuterPosition = [0,0,1,1];
            ax.PlotBoxAspectRatio = [1,1,1];
            % app.UIAxes.ColorOrder = [0 0 0; 1 0 0; 0 0 1; 0.73 0.21 0.73;...
            %        0.47 0.67 0.19; 0.85 0.33 0.10; 1 0 1; 0.64 0.08 0.18; ...
            %        .61 .51 .74; 0 1 0; 0 1 1];
            app.UIAxes.ColorOrder = [31,120,180;51,160,44;...
                227,26,28;255,127,0;...
                106,61,154;166,206,227;...
                178,223,138;251,154,153;...
                253,191,111;202,178,214]./256;
            
            ax.FontWeight = 'normal';
            ax.FontSize = 24;
            ax.LineWidth = 2;
            ax.XLabel.String = 'Potential \itV\rm vs. ref / V';
            ax.YLabel.String = 'Current, \itI\rm / mA';
            ax.XMinorTick = 'on';
            ax.YMinorTick = 'on';
            ax.Box = 'on';
        end

        function p_plot_CV(app)
            V = app.V;
            V = V(1:end);
            red = app.red;
            ox = app.ox;
                        
            rates_TT = cell2mat(table2array(app.RateTable.Data(:,2)));
            
            for i = 1:length(rates_TT)
                if rates_TT(i) == 1
%                     ox(:,i) = sgolayfilt(ox(:,i),1,501);
%                     red(:,i) = sgolayfilt(red(:,i),1,501);
                    appendredoxstart = find(~isnan(ox(:,i)),1,'first');
                    appendredoxend = find(~isnan(ox(:,i)),1,'last');
                    red(appendredoxstart,i) = ox(appendredoxstart,i);
                    red(appendredoxend,i) = ox(appendredoxend,i);
                end
            end
            
            lowerlim = app.LowerVLimitEditField.Value;
            upperlim = app.UpperVLimitEditField.Value;

%             baseline_correct = ox(find(~isnan(ox),1),rates_TT)
%             y = [red(1:50:end,rates_TT)-baseline_correct(1,rates_TT); ox(1:50:end,rates_TT)-baseline_correct(1,rates_TT)];
            
            V = [V,V]';
            y = [red(1:end,rates_TT); ox(1:end,rates_TT)];
            
            maxlim = max(max(ox(:,rates_TT)));
            minlim = min(min(red(:,rates_TT)));
            tickspace = abs(round((maxlim-minlim)/7,1,'significant'));
            % tickspacex = abs(round((upperlim-lowerlim)/7,1,'significant'));
            mintick = round(floor((minlim)/tickspace)*tickspace,2,'significant');
            maxtick = round(ceil((maxlim)/tickspace)*tickspace,2,'significant');
                    
            cla(app.UIAxes)
            ax = app.UIAxes;
            % app.UIAxes.ColorOrder = [0 0 0; 1 0 0; 0 0 1; 0.73 0.21 0.73;...
            %        0.47 0.67 0.19; 0.85 0.33 0.10; 1 0 1; 0.64 0.08 0.18; ...
            %        .61 .51 .74; 0 1 0; 0 1 1];
    
            h1 = plot(ax,V,y,'LineWidth',3,'Tag','CVData');
                    
            % ax.FontWeight = 'bold';
            % ax.LineWidth = 2;
            % % ax.XTick = [-9*tickspacex:tickspacex:9*tickspacex];
            % ax.XLabel.String = 'Potential \itV\rm vs. ref / V';
            % ax.YLabel.String = 'Current, \itI\rm / mA';
            ax.XLim = [lowerlim-(tickspace/2) upperlim+(tickspace/2)];
            % % ax.YLim = [mintick-(tickspace/2) maxtick+(tickspace/2)];
            % % ax.YTick = [-9*tickspace:tickspace:9*tickspace];

            % load legend when selected rates are changed
            legend(app.UIAxes, strcat(app.PlotontoratemVsDropDown.Items," mV/s"), 'Location','northeast','FontSize',24, 'Box','off')
            axis_formatting(app, app.UIAxes)
            disp('Variable Rate CV Plotting Complete')
        end
        
        function results = p_plot_CV_step12_edgeprotect(app)
        % function results = p_plot_CV_step12_edgeprotect(app)
            V0  = app.V;
            ox  = app.ox;
            red = app.red;
    
            if size(ox,1) < size(ox,2) && size(red,1) < size(red,2), ox=ox.'; red=red.'; end
            [N,S] = size(ox);
        
            if isequal(size(V0),[N,S]), V = V0;
            elseif isvector(V0) && numel(V0)==N, V = repmat(V0(:),1,S);
            elseif isequal(size(V0),[S,N]),      V = V0.';
            else, error('V must be [N×S], [N×1]/[1×N], or [S×N]^T.');
            end
    
            loV = app.LowerVLimitEditField.Value;
            hiV = app.UpperVLimitEditField.Value;
    
            centralMask = @(v) (v>=loV+0.10*(hiV-loV)) & (v<=hiV-0.10*(hiV-loV));
        
            hasP=false(1,S); hasM=false(1,S);
            lo=max(1,round(0.1*N)); hi=min(N,round(0.9*N));
            disp(lo)
            disp(hi)
    
            for i=1:S
                % xi=ox(lo:hi,i); if numel(xi)>=5, hasP(i)=~isempty(findpeaks(xi,'MinPeakProminence',0.08*(max(xi)-min(xi)))); end
                % ri=red(lo:hi,i);if numel(ri)>=5, hasM(i)=~isempty(findpeaks(-ri,'MinPeakProminence',0.08*(max(ri)-min(ri)))); end
                xi=ox(lo:hi,i); if numel(xi)>=5, hasP(i)=~isempty(findpeaks(xi,'MinPeakProminence',0.08*range(xi,'omitnan'))); end
                ri=red(lo:hi,i);if numel(ri)>=5, hasM(i)=~isempty(findpeaks(-ri,'MinPeakProminence',0.08*range(ri,'omitnan'))); end
            end
            if ~any(hasP) && ~any(hasM)
                local_plot_unified(app, app.UIAxes,V,ox,V,red,'Potential, E (V)','Current, \itI\rm/mA',[]); % helper function
                results=struct('mode','non-faradaic (no calibration)'); return
            end
        
            [Epk_ox,Ipk_ox] = one_peak_per_scan(app, V,ox,true, centralMask, S);
            [Epk_rd,Ipk_rd] = one_peak_per_scan(app, V,red,false,centralMask, S);
            [~,R_ox,r2_ox,n_ox] = fit_E_equals_a_plus_RI(app, Epk_ox,Ipk_ox); % helper function
            [~,R_rd,r2_rd,n_rd] = fit_E_equals_a_plus_RI(app, Epk_rd,Ipk_rd); % helper function
        
            use_ox = n_ox>=2 && r2_ox>=0.98;
            use_rd = n_rd>=2 && r2_rd>=0.98;
            if use_ox && use_rd
                if abs(R_ox) >= abs(R_rd), R = R_ox; else, R = R_rd; end
            elseif use_ox
                R = R_ox;
            elseif use_rd
                R = R_rd;
            else
                R = 0; warning('No reliable R (R^2<0.98 or <2 points). Using R=0.');
            end
    
            E_dep_ox  = V - R*ox;
            E_dep_red = V + R*red;
        
            [haveNu,nu] = get_scan_rates(app,V);
            if haveNu
                midBand = [loV+0.35*(hiV-loV), loV+0.65*(hiV-loV)];
                mask_mid = V>=midBand(1) & V<=midBand(2);
                M = nan(1,S);
                for s=1:S
                    Ei = [E_dep_ox(mask_mid(:,s),s); E_dep_red(mask_mid(:,s),s)];
                    M(s) = mean(Ei,'omitnan');
                end
                good = isfinite(M) & isfinite(nu);
                if nnz(good)>=2
                    X = [ones(nnz(good),1), nu(good).']; b = X \ M(good).';
                    b0 = b(1); b1 = b(2);
                else
                    b0 = median(M,'omitnan'); b1 = 0;
                end
                base = b0 + b1.*nu;      % 1×S
                E_cal_ox  = E_dep_ox  - repmat(base, N, 1);
                E_cal_red = E_dep_red - repmat(base, N, 1);
                note2 = sprintf('Step 2 applied: b0=%.4g, b1=%.4g.', b0, b1);
            else
                E_cal_ox = E_dep_ox; E_cal_red = E_dep_red; b0=NaN; b1=NaN;
                note2 = 'Step 2 skipped (no scan rate).';
            end
        
            protectFrac = 0.12;    transFrac = 0.05;
            protectW = max(30, round(protectFrac*N));
            transW   = max(10, round(transFrac*N));
            smooth01 = @(t) (3*t.^2 - 2*t.^3);
        
            wEdge = ones(N,1);
            if protectW>0
                wEdge(1:protectW) = 0;
                if protectW+transW <= N
                    t = linspace(0,1,transW).';
                    wEdge(protectW+(1:transW)) = smooth01(t);
                end
            end
            if protectW>0
                wEdge(end-protectW+1:end) = 0;
                if protectW+transW <= N
                    t = linspace(1,0,transW).';
                    wEdge(end-protectW-transW+1 : end-protectW) = smooth01(t);
                end
            end
        
            E_fin_ox  = V + repmat(wEdge,1,S) .* (E_cal_ox  - V);
            E_fin_red = V + repmat(wEdge,1,S) .* (E_cal_red - V);
    
            % store calibrated curves for deconvolution
            app.E_ox_all =  E_fin_ox;
            app.E_red_all = E_fin_red;
    
            % set calibration state to true
            app.hasCal = 1;
        
            labels_for_legend = [];
            sr = [];
            if isprop(app,'rate') && ~isempty(app.rate)
                sr = app.rate;                 % accept any shape
            elseif exist('nu','var') && ~isempty(nu)
                sr = nu;
            end
            if ~isempty(sr)
                sr = sr(:).';                       % row
                if numel(sr) < S,  sr(end+1:S) = sr(end); end  % pad if short
                if numel(sr) > S,  sr = sr(1:S);   end         % trim if long
                labels_for_legend = cellstr(string(sr) + " mV/s");  % cellstr -> legend-safe
            end
        
            local_plot_unified(app, app.UIAxes, E_fin_ox, ox, E_fin_red, red, ...
                               'Calibrated potential, E_{cal} (V)', 'Current, \itI\rm/mA', ...
                               labels_for_legend); % helper function
        
            if isgraphics(app.UIFigure)
                setappdata(app.UIFigure,'E_ox',  E_fin_ox);
                setappdata(app.UIFigure,'E_red', E_fin_red);
            end
        
            results = struct('R_ohms',R,'R2_ox',r2_ox,'R2_red',r2_rd, ...
                             'n_points_ox',n_ox,'n_points_red',n_rd, ...
                             'haveNu',haveNu,'nu',nu,'b0',b0,'b1',b1, ...
                             'note',sprintf('%s | edge-protect: protect=%d (%.0f%%), trans=%d (%.0f%%)', ...
                                 note2, protectW,100*protectW/N, transW,100*transW/N));
            fprintf('Step 1 R=%.3g Ω (R² anodic=%.3f, cathodic=%.3f). %s\n', R, r2_ox, r2_rd, results.note);
        end

        function [Epk,Ipk] = one_peak_per_scan(app, Vx,Ix,isAnodic,maskfun, S)
            Epk = nan(1,S); Ipk = nan(1,S);
            for i=1:S
                cen = maskfun(Vx(:,i)); k = find(cen); if isempty(k), continue; end
                if isAnodic, [p,loc] = findpeaks(Ix(k,i));
                else,        [p,loc] = findpeaks(-Ix(k,i)); p = -p;
                end
                if ~isempty(p)
                    [~,m] = max(abs(p)); idx = k(loc(m));
                    Epk(i) = Vx(idx,i); Ipk(i) = Ix(idx,i);
                end
            end
            keep = isfinite(Epk) & isfinite(Ipk);
            if any(keep)
                medI = median(Ipk(keep)); 
                sI = 1.4826*mad(Ipk(keep),1);
                bad = abs(Ipk - medI) > 3*sI;
                Epk(bad) = NaN; Ipk(bad) = NaN;
            end
        end

        function [a,Rfit,r2,n] = fit_E_equals_a_plus_RI(app,E,I)
            E=E(:); I=I(:); good=isfinite(E)&isfinite(I); E=E(good); I=I(good); n=numel(E);
            if n<2, a=NaN; Rfit=0; r2=NaN; return; end
            [b,stats] = robustfit(I,E); a=b(1); Rfit=b(2);
            ss_res = stats.s^2 * max(n-2,1); ss_tot = sum((E-mean(E)).^2);
            r2 = max(0, 1 - ss_res/ss_tot);
        end

        function [ok,nu_] = get_scan_rates(app_,V_)
            ok=false; nu_=[];
            if isprop(app_,'ScanRates') && ~isempty(app_.ScanRates)
                nu_=app_.ScanRates(:).'; ok = numel(nu_)==S && all(isfinite(nu_));
                if ok, return; end
            end
            if isprop(app_,'Time') && ~isempty(app_.Time)
                t = app_.Time;
                if isequal(size(t),[N,S])
                    nu_=zeros(1,S);
                    for ii=1:S
                        r = abs(diff(V_(:,ii)))./diff(t(:,ii));
                        nu_(ii)=median(r(isfinite(r)&r>0));
                    end
                    ok = all(isfinite(nu_)) & all(nu_>0);
                elseif isvector(t) && numel(t)==N
                    dt = diff(t(:)); nu_=zeros(1,S);
                    for ii=1:S
                        r = abs(diff(V_(:,ii)))./dt;
                        nu_(ii)=median(r(isfinite(r)&r>0));
                    end
                    ok = all(isfinite(nu_)) & all(nu_>0);
                end
            end
        end
        % end
        % 
        % % function for plotting dotted lines
        function local_plot_unified(app, ax, Xox, Iox, Xrd, Ird, xlab, ylab, legendLabels)
            cla(ax);
            hold(ax,'on');

            customColors = app.UIAxes.ColorOrder;

            [~,S] = size(Iox);
            if size(Xox,2)~=S, Xox = repmat(Xox(:,1),1,S); end
            if size(Xrd,2)~=S, Xrd = repmat(Xrd(:,1),1,S); end

            h = gobjects(1,S);
            for i = 1:S
                colorIdx = mod(i-1, length(customColors)) + 1;
                thisColor = customColors(colorIdx, :);

                h(i) = plot(ax, Xox(:,i), Iox(:,i), 'LineWidth',3, 'Color', thisColor);
                plot(ax, Xrd(:,i), Ird(:,i), 'LineWidth',3,'Color', thisColor);
            end

            % Append labels to legend
            if nargin>=8 && ~isempty(legendLabels)
                legend(ax, h, legendLabels, 'Location','northeast', 'FontSize',24, 'Box','off');
            else
                legend(ax, h, "data"+string(1:S), 'Location','northeast', 'FontSize',24, 'Box', 'off');
            end

            ax.FontWeight='bold'; ax.LineWidth=2;
            ax.XLabel.String = xlab;
            ax.YLabel.String = ylab;

            % X axis 
            xmin=min([Xox(:);Xrd(:)]); xmax=max([Xox(:);Xrd(:)]);
            spanX=max(xmax-xmin,eps); dx=max(abs(round(spanX/7,1,'significant')),1e-6);
            ax.XTick=(xmin-2*dx):dx:(xmax+2*dx); ax.XLim=[xmin-0.5*dx,xmax+0.5*dx];

            % Y axis
            ymin=min([Iox(:);Ird(:)]); ymax=max([Iox(:);Ird(:)]);
            spanY=max(ymax-ymin,eps); dy=max(abs(round(spanY/7,1,'significant')),1e-6);
            mint=round(floor(ymin/dy)*dy,2,'significant'); maxt=round(ceil(ymax/dy)*dy,2,'significant');
            ax.YLim=[mint-0.5*dy, maxt+0.5*dy]; ax.YTick=(mint-9*dy):dy:(maxt+9*dy);

            % Dotted original curves for all active columns (hide from legend)
            rates_TT = cell2mat(table2array(app.RateTable.Data(:,2)));
            cols  = find(rates_TT == 1);
            ncols = numel(cols);
            y_dub = [app.red(:,cols); app.ox(:,cols)];                 % (2N x ncols)
            X_dub = repmat(app.V(:), 2, 1);                           % (2N x 1)
            X_dub = repmat(X_dub, 1, ncols);                         % (2N x ncols)

            plot(ax, X_dub, y_dub, 'LineStyle', ':', 'LineWidth', 1, ...
                 'Tag', 'CVData', 'HandleVisibility','off');

            % % Peak markers (hide from legend)
            % plot(ax, V_ox_pp,  pp_ox,  'o', 'HandleVisibility','off');
            % plot(ax, V_red_pp, pp_red, 'o', 'HandleVisibility','off');

            % Create legend from DisplayName entries
            legend(ax,'show');
            hold(ax,'off');

            disp('Variable Rate CV Plotting Complete');
        end

        function dep_check = checkDependencies(app)
            % Map functions to toolbox display names
            DEPS = {
                'findpeaks', 'Signal Processing Toolbox';
                'robustfit','Statistics and Machine Learning Toolbox';
                'mad','Statistics and Machine Learning Toolbox';
            };
        
            % Installed toolboxes
            v = ver;
            installed = {v.Name};
        
            fprintf('\n=============== Dependency Check ===============\n');
        
            dep_check = true; % assume success unless something is missing
        
            for i = 1:size(DEPS,1)
                func = DEPS{i,1};
                toolbox = DEPS{i,2};
        
                % Function missing ⇒ toolbox missing
                if isempty(which(func)) || ~ismember(toolbox, installed)
                    msg = sprintf(['Error using %s\n',...
                                   '%s requires the %s.\n'], ...
                                   func, upper(func), toolbox);
        
                    fprintf('  ✗ %s\n', msg);
        
                    ok = false;
                    fprintf('Startup aborted due to missing dependencies.\n');
                    fprintf('Please install all toolboxes before continuing.\n');
                    fprintf('=================================================\n\n');
                    return      % <--- DO NOT throw error, just stop the check
                end
        
                % Otherwise dependency satisfied
                fprintf('  ✓ %s installed\n', toolbox);
            end
        
            fprintf('All dependencies satisfied.\n');
            fprintf('=================================================\n\n');
        end
        function [ox_slopes, red_slopes] = Peak_corr(app)
        % Peak_corr  -- detect peaks across selected scans, compute slopes per-peak
        % Usage:
        %   [ox_slopes, red_slopes] = Peak_corr(app)
        %
        % Returns:
        %   ox_slopes  : num_peaks × 1 vector of slopes (oxidation)
        %   red_slopes : num_peaks × 1 vector of slopes (reduction)
        
            % --- Inputs & orientation ------------------------------------------------
            V = app.V(:);                 % ensure column (N x 1)
            ox  = app.ox;                 % expected N x S
            red = app.red;                % expected N x S
            rates_TT = cell2mat(table2array(app.RateTable.Data(:,2)));
            active_cols = find(rates_TT == 1);   % true column indices in ox/red
        
            if size(ox,1) < size(ox,2)
                ox = ox.'; red = red.';   % transpose if needed
            end
            [N,S] = size(ox);
            if ~isequal(size(red), [N,S])
                error('ox and red must be same size: ox=%dx%d red=%dx%d', N,S,size(red,1),size(red,2));
            end
        
            % --- determine number of peaks to extract (safe default) ---------------
            % Use first column as heuristic (like your original code)
            tmpcol = ox(:,1);
            tmpcol = tmpcol(~isnan(tmpcol));
            if isempty(tmpcol)
                num_peaks = 3;
            else
                % robust estimate of peaks in first column
                tmpPeaks = findpeaks(tmpcol,'MinPeakProminence',max(tmpcol)*0.1);
                num_peaks = max(1, length(tmpPeaks));
            end
        
            % --- preallocate containers ---------------------------------------------
            nActive = numel(active_cols);
            pp_ox     = nan(num_peaks, nActive);   % peak values (ox)
            pp_ox_idx = nan(num_peaks, nActive);   % indices into V (rows)
            V_ox_pp   = nan(num_peaks, nActive);   % voltages at those peaks
        
            pp_red     = nan(num_peaks, nActive);
            pp_red_idx = nan(num_peaks, nActive);
            V_red_pp   = nan(num_peaks, nActive);
        
            E_ox_all  = nan(N,S);
            E_red_all = nan(N,S);
        
            % Edge-protection smoothing weights (same as you used before)
            protectFrac = 0.12; transFrac = 0.05;
            protectW = max(30, round(protectFrac*N));
            transW   = max(10, round(transFrac*N));
            smooth01 = @(t) (3*t.^2 - 2*t.^3);
            wEdge = ones(N,1);
            if protectW>0
                wEdge(1:protectW) = 0;
                if protectW+transW <= N
                    t = linspace(0,1,transW).';
                    wEdge(protectW + (1:transW)) = smooth01(t);
                end
                wEdge(end-protectW+1:end) = 0;
                if protectW+transW <= N
                    t = linspace(1,0,transW).';
                    wEdge(end-protectW-transW+1:end-protectW) = smooth01(t);
                end
            end
        
            % --- Loop: detect peaks per active scan (safe indexing) ---------------
            iActive = 0;
            for cnt = 1:S
                if rates_TT(cnt) ~= 1
                    continue
                end
                iActive = iActive + 1;   % column in the compressed active arrays
        
                % ensure this column exists (defensive)
                if cnt < 1 || cnt > S
                    continue
                end
        
                % --- end-point assignments like original code -----------------------
                idx_first = find(~isnan(ox(:,cnt)),1,'first');
                idx_last  = find(~isnan(ox(:,cnt)),1,'last');
                if ~isempty(idx_first) && idx_first>=1 && idx_first<=N && ~isnan(ox(idx_first,cnt))
                    red(idx_first,cnt) = ox(idx_first,cnt);
                end
                if ~isempty(idx_last) && idx_last>=1 && idx_last<=N && ~isnan(ox(idx_last,cnt))
                    red(idx_last,cnt) = ox(idx_last,cnt);
                end
        
                % --- oxidation peaks: operate on the whole column but robust to NaNs
                valid_ox_mask = ~isnan(ox(:,cnt));
                if sum(valid_ox_mask) >= 3 && range(ox(valid_ox_mask,cnt)) > 0
                    [pOx, locOx] = findpeaks(ox(valid_ox_mask,cnt), ...
                                             'MinPeakProminence', max(ox(valid_ox_mask,cnt))*0.1);
                    if ~isempty(pOx)
                        nP = min(num_peaks, numel(pOx));
                        pp_ox(1:nP, iActive) = pOx(1:nP);
                        valid_idx = find(valid_ox_mask);               % map locOx -> global index
                        loc_use = locOx(1:nP);
                        pp_ox_idx(1:nP, iActive) = valid_idx(loc_use);
                        V_ox_pp(1:nP, iActive) = V(pp_ox_idx(1:nP, iActive));
                    end
                end
        
                % --- reduction peaks (we find minima via negation) --------------
                valid_red_mask = ~isnan(red(:,cnt));
                if sum(valid_red_mask) >= 3 && range(red(valid_red_mask,cnt)) > 0
                    [pRed, locRed] = findpeaks(-red(valid_red_mask,cnt), ...
                                               'MinPeakProminence', max(abs(red(valid_red_mask,cnt)))*0.1);
                    if ~isempty(pRed)
                        nR = min(num_peaks, numel(pRed));
                        pp_red(1:nR, iActive) = -pRed(1:nR);  % restore sign
                        valid_idx = find(valid_red_mask);
                        loc_use = locRed(1:nR);
                        pp_red_idx(1:nR, iActive) = valid_idx(loc_use);
                        V_red_pp(1:nR, iActive) = V(pp_red_idx(1:nR, iActive));
                    end
                end
            end
        
            % If no active scans found, return empty slopes and leave app unchanged
            if iActive == 0
                ox_slopes = NaN(num_peaks,1);
                red_slopes = NaN(num_peaks,1);
                warning('Peak_corr: no active scans selected in rates_TT.');
                return
            end
        
            % Trim arrays to actual active number (in case some columns unused)
            pp_ox     = pp_ox(:,1:iActive);
            pp_ox_idx = pp_ox_idx(:,1:iActive);
            V_ox_pp   = V_ox_pp(:,1:iActive);
        
            pp_red     = pp_red(:,1:iActive);
            pp_red_idx = pp_red_idx(:,1:iActive);
            V_red_pp   = V_red_pp(:,1:iActive);
        
            % --- Compute slopes per peak row across active scans -------------------
            ox_slopes = nan(num_peaks,1);
            red_slopes = nan(num_peaks,1);
        
            for p = 1:num_peaks
                % oxidation: take columns where both V_ox_pp and pp_ox present
                goodOx = isfinite(V_ox_pp(p,:)) & isfinite(pp_ox(p,:));
                if sum(goodOx) >= 2
                    x = V_ox_pp(p, goodOx);
                    y = pp_ox(p, goodOx);
                    coeff = polyfit(x(:), y(:), 1);
                    ox_slopes(p) = coeff(1);   % slope
                else
                    ox_slopes(p) = NaN;
                end
        
                % reduction
                goodRed = isfinite(V_red_pp(p,:)) & isfinite(pp_red(p,:));
                if sum(goodRed) >= 2
                    x = V_red_pp(p, goodRed);
                    y = pp_red(p, goodRed);
                    coeff = polyfit(x(:), y(:), 1);
                    red_slopes(p) = coeff(1);
                else
                    red_slopes(p) = NaN;
                end
            end
        
            % --- Choose scalar slopes to apply for correction (use mean of valid peak slopes)
            ox_slope_used  = mean(ox_slopes,'omitnan');
            red_slope_used = mean(red_slopes,'omitnan');
            if ~isfinite(ox_slope_used) || ox_slope_used == 0, ox_slope_used = 1; end
            if ~isfinite(red_slope_used) || red_slope_used == 0, red_slope_used = 1; end
        
            % --- Build corrected potentials for all scans (only active ones corrected)
            for cnt = 1:S
                if rates_TT(cnt) ~= 1, continue; end
                vox  = ox(:,cnt);
                vred = red(:,cnt);
        
                % ensure column
                vox = vox(:); vred = vred(:);
        
                Vox  = V - vox./ox_slope_used;
                Vred = V - vred./red_slope_used;
        
                E_ox_all(:,cnt)  = V + wEdge .* (Vox - V);
                E_red_all(:,cnt) = V + wEdge .* (Vred - V);
            end
        
            % --- Plotting (corrected + original dotted + peak markers) ----------
            ax = app.UIAxes;
            cla(ax); hold(ax,'on');
        
            % corrected curves (only active)
            for k = 1:iActive
                col = active_cols(k);
                if all(isnan(E_ox_all(:,col))) == false
                    plot(ax, E_ox_all(:,col), ox(:,col), 'LineWidth', 3, 'Color', [0 0 1]);
                    plot(ax, E_red_all(:,col), red(:,col), 'LineWidth', 3, 'Color', [1 0 0]);
                end
            end
        
            % original dotted curves (stack active columns)
            if ~isempty(active_cols)
                plot(ax, repmat(V,1,numel(active_cols)), [red(:,active_cols), ox(:,active_cols)], ...
                    'LineStyle',':','LineWidth',1,'Color',[0 0 0]);
            end
        
            % peak markers (ox)
            idxGoodOx = isfinite(pp_ox_idx) & isfinite(pp_ox);
            if any(idxGoodOx(:))
                % convert linear subscripts to vectors for plotting: V(indices), peaks
                v_inds = pp_ox_idx(idxGoodOx);
                v_vals = pp_ox(idxGoodOx);
                plot(ax, V(v_inds), v_vals, 'o', 'MarkerEdgeColor', [0 0 1]);
            end
            % reduction peaks
            idxGoodRed = isfinite(pp_red_idx) & isfinite(pp_red);
            if any(idxGoodRed(:))
                v_inds = pp_red_idx(idxGoodRed);
                v_vals = pp_red(idxGoodRed);
                plot(ax, V(v_inds), v_vals, 'o', 'MarkerEdgeColor', [1 0 0]);
            end
        
            hold(ax,'off');
        
            % --- Store results in app for downstream functions ---------------------
            app.E_ox_all  = E_ox_all;
            app.E_red_all = E_red_all;
            app.hasCal    = 1;
        
            % also store slopes in the app so other functions can access them:
            app.PeakCorr_ox_slopes  = ox_slopes;
            app.PeakCorr_red_slopes = red_slopes;
        
            disp('Peak_corr: completed. Slopes (NaN = insufficient data).');
        
        end

        % function Peak_corr(app)
        %     % --- Extract raw data ---
        %     V   = app.V(:);           % ensure column
        %     ox  = app.ox;             % N x S
        %     red = app.red;            % N x S
        %     rates_TT = cell2mat(table2array(app.RateTable.Data(:,2)));
        %     active_cols = find(rates_TT==1);
        % 
        %     [N,S] = size(ox);
        % 
        %     % --- Preallocate arrays ---
        %     max_peaks = 10; % max peaks per scan, adjust if needed
        %     pp_ox     = nan(max_peaks,S);
        %     pp_ox_idx = nan(max_peaks,S);
        %     pp_red    = nan(max_peaks,S);
        %     pp_red_idx= nan(max_peaks,S);
        % 
        %     E_ox_all  = nan(N,S);
        %     E_red_all = nan(N,S);
        % 
        %     P_ox      = nan(2,S);
        %     P_red     = nan(2,S);
        % 
        %     % --- Edge protection factor ---
        %     protectFrac = 0.12; transFrac = 0.05;
        %     protectW = max(30, round(protectFrac*N));
        %     transW   = max(10, round(transFrac*N));
        %     smooth01 = @(t) (3*t.^2 - 2*t.^3);
        %     wEdge = ones(N,1);
        %     if protectW>0
        %         wEdge(1:protectW) = 0;
        %         if protectW+transW <= N
        %             t = linspace(0,1,transW).';
        %             wEdge(protectW+(1:transW)) = smooth01(t);
        %         end
        %         wEdge(end-protectW+1:end) = 0;
        %         if protectW+transW <= N
        %             t = linspace(1,0,transW).';
        %             wEdge(end-protectW-transW+1:end-protectW) = smooth01(t);
        %         end
        %     end
        % 
        %     % --- Loop over active scans ---
        %     for k = 1:length(active_cols)
        %         col = active_cols(k);
        % 
        %         % --- Safe edge assignment ---
        %         idx_first = find(~isnan(ox(:,col)),1,'first');
        %         idx_last  = find(~isnan(ox(:,col)),1,'last');
        %         if ~isempty(idx_first), red(idx_first,col) = ox(idx_first,col); end
        %         if ~isempty(idx_last),  red(idx_last,col)  = ox(idx_last,col); end
        % 
        %         % --- Find peaks ---
        %         valid_ox  = ~isnan(ox(:,col));
        %         valid_red = ~isnan(red(:,col));
        % 
        %         if any(valid_ox)
        %             [p_ox, loc_ox] = findpeaks(ox(valid_ox,col), 'MinPeakProminence', 0.1*range(ox(valid_ox,col)));
        %             nP = min(length(p_ox), max_peaks);
        %             pp_ox(1:nP,col) = p_ox(1:nP);
        %             valid_idx = find(valid_ox);
        %             pp_ox_idx(1:nP,col) = valid_idx(loc_ox(1:nP));
        %         end
        % 
        %         if any(valid_red)
        %             [p_red, loc_red] = findpeaks(-red(valid_red,col), 'MinPeakProminence', 0.1*range(red(valid_red,col)));
        %             nP = min(length(p_red), max_peaks);
        %             pp_red(1:nP,col) = -p_red(1:nP);
        %             valid_idx = find(valid_red);
        %             pp_red_idx(1:nP,col) = valid_idx(loc_red(1:nP));
        %         end
        % 
        %         % --- Linear fit ---
        %         if any(~isnan(pp_ox(:,col)))
        %             xOx = V(pp_ox_idx(~isnan(pp_ox(:,col)),col));
        %             yOx = pp_ox(~isnan(pp_ox(:,col)),col);
        %             if length(xOx) >= 2
        %                 P_ox(:,col) = polyfit(xOx, yOx, 1);
        %             end
        %         end
        %         if any(~isnan(pp_red(:,col)))
        %             xRed = V(pp_red_idx(~isnan(pp_red(:,col)),col));
        %             yRed = pp_red(~isnan(pp_red(:,col)),col);
        %             if length(xRed) >= 2
        %                 P_red(:,col) = polyfit(xRed, yRed, 1);
        %             end
        %         end
        %     end
        % 
        %     % --- Average slopes ---
        %     ox_slope  = mean(P_ox(1,active_cols),'omitnan'); 
        %     red_slope = mean(P_red(1,active_cols),'omitnan');
        %     if isnan(ox_slope) || ox_slope==0, ox_slope=1; end
        %     if isnan(red_slope) || red_slope==0, red_slope=1; end
        % 
        %     % --- Apply correction ---
        %     for k = 1:length(active_cols)
        %         col = active_cols(k);
        %         Vox  = V - ox(:,col)/ox_slope;
        %         Vred = V - red(:,col)*red_slope;
        %         E_ox_all(:,col)  = V + wEdge.*(Vox - V);
        %         E_red_all(:,col) = V + wEdge.*(Vred - V);
        %     end
        % 
        %     % --- Plot ---
        %     cla(app.UIAxes)
        %     hold(app.UIAxes,'on')
        %     for k = 1:length(active_cols)
        %         col = active_cols(k);
        %         plot(app.UIAxes,E_ox_all(:,col), ox(:,col),'LineWidth',3,'Color',[0 0 1])
        %         plot(app.UIAxes,E_red_all(:,col), red(:,col),'LineWidth',3,'Color',[1 0 0])
        %     end
        %     size(repmat(V,1,length(active_cols)))
        %     size([red(:,active_cols), ox(:,active_cols)])
        %     % /Users/lewigo/Library/CloudStorage/OneDrive-DanmarksTekniskeUniversitet/Manuscripts/Parham_Disentangling_CV/New Data/Formatted_multiple_input.txt
        %     % Original dotted curves
        %     plot(app.UIAxes, repmat(V,1,2*length(active_cols)), [red(:,active_cols), ox(:,active_cols)], ...
        %         'LineStyle',':','LineWidth',1,'Color',[0 0 0])
        % 
        %     % Peak markers
        %     finiteOx = ~isnan(pp_ox_idx) & ~isnan(pp_ox);
        %     finiteRed = ~isnan(pp_red_idx) & ~isnan(pp_red);
        %     if any(finiteOx(:))
        %         plot(app.UIAxes, V(pp_ox_idx(finiteOx)), pp_ox(finiteOx),'o','Color',[0 0 1])
        %     end
        %     if any(finiteRed(:))
        %         plot(app.UIAxes, V(pp_red_idx(finiteRed)), pp_red(finiteRed),'o','Color',[1 0 0])
        %     end
        %     hold(app.UIAxes,'off')
        %     hLeg = legend(app.UIAxes);
        % 
        %     set(hLeg,'visible','off')
        % 
        %     % --- Store ---
        %     app.E_ox_all  = E_ox_all;
        %     app.E_red_all = E_red_all;
        %     app.hasCal    = 1;
        % 
        %     disp('Variable Rate CV Plotting Complete');
        % end

        % function Peak_corr(app)
        %     % --- Extract raw data ---
        %     V  = app.V;          % original V (N x 1 or N x S)
        %     ox = app.ox;         % N x S expected
        %     red = app.red;       % N x S expected
        %     rates_TT = cell2mat(table2array(app.RateTable.Data(:,2)));
        %     active_cols = find(rates_TT==1);
        % 
        %     if isrow(V), V = V(:); end
        %     if size(ox,1) < size(ox,2)
        %         ox = ox.'; red = red.';   % transpose if needed
        %     end
        %     [N,S] = size(ox);
        %     [Nred, Sred] = size(red);
        %     if N ~= Nred || S ~= Sred
        %         error('ox and red must have same size. ox is %dx%d, red is %dx%d', N, S, Nred, Sred);
        %     end
        % 
        %     % --- Central mask ---
        %     loV = app.LowerVLimitEditField.Value;
        %     hiV = app.UpperVLimitEditField.Value;
        %     centralMask = @(v) (v >= loV + 0.10*(hiV-loV)) & (v <= hiV - 0.10*(hiV-loV));
        % 
        %     % --- Edge protection ---
        %     protectFrac = 0.12; transFrac = 0.05;
        %     protectW = max(30, round(protectFrac*N));
        %     transW   = max(10, round(transFrac*N));
        %     smooth01 = @(t) (3*t.^2 - 2*t.^3);
        %     wEdge = ones(N,1);
        %     if protectW > 0
        %         wEdge(1:protectW) = 0;
        %         if protectW+transW <= N
        %             t = linspace(0,1,transW).';
        %             wEdge(protectW + (1:transW)) = smooth01(t);
        %         end
        %         wEdge(end-protectW+1:end) = 0;
        %         if protectW+transW <= N
        %             t = linspace(1,0,transW).';
        %             wEdge(end-protectW-transW+1:end-protectW) = smooth01(t);
        %         end
        %     end
        % 
        %     % --- Preallocate ---
        %     firstCol = find(~isnan(ox(:,1)),1);
        %     if isempty(firstCol)
        %         num_peaks = 5;
        %     else
        %         tmp = ox(:,1);
        %         tmp = tmp(~isnan(tmp));
        %         num_peaks = max(1,length(findpeaks(tmp,'MinPeakProminence',0.1*range(tmp))));
        %     end
        % 
        %     pp_ox_idx  = nan(num_peaks,S);
        %     pp_red_idx = nan(num_peaks,S);
        %     pp_ox      = nan(num_peaks,S);
        %     pp_red     = nan(num_peaks,S);
        %     E_ox_all   = nan(N,S);
        %     E_red_all  = nan(N,S);
        %     P_ox       = nan(2,S);
        %     P_red      = nan(2,S);
        % 
        %     % --- Mask ---
        %     mask_global = centralMask(V);
        %     idx_mask = find(mask_global);
        % 
        %     % --- Loop explicitly over active columns ---
        %     for k = 1:length(active_cols)
        %         col = active_cols(k);  % actual scan index
        % 
        %         % --- First/last valid indices ---
        %         idx_first = find(~isnan(ox(:,col)),1,'first');
        %         idx_last  = find(~isnan(ox(:,col)),1,'last');
        % 
        %         if ~isempty(idx_first) && idx_first >= 1 && idx_first <= N
        %             if ~isnan(ox(idx_first,col))
        %                 red(idx_first,col) = ox(idx_first,col);
        %             end
        %         end
        %         if ~isempty(idx_last) && idx_last >= 1 && idx_last <= N
        %             if ~isnan(ox(idx_last,col))
        %                 red(idx_last,col) = ox(idx_last,col);
        %             end
        %         end
        % 
        %         % --- Oxidation peaks ---
        %         yox = ox(mask_global,col); yox = yox(:);
        %         if ~all(isnan(yox)) && numel(yox) >= 3 && range(yox(~isnan(yox))) > 0
        %             [p_ox, loc_ox] = findpeaks(yox,'MinPeakProminence',0.08*range(yox(~isnan(yox))));
        %             nP = numel(p_ox);
        %             if nP > 0
        %                 pp_ox(1:nP,col) = p_ox;
        %                 pp_ox_idx(1:nP,col) = idx_mask(loc_ox);
        %             end
        %         end
        % 
        %         % --- Reduction peaks ---
        %         yred = red(mask_global,col); yred = yred(:);
        %         if ~all(isnan(yred)) && numel(yred) >= 3 && range(yred(~isnan(yred))) > 0
        %             [p_red, loc_red] = findpeaks(-yred,'MinPeakProminence',0.08*range(yred(~isnan(yred))));
        %             nPr = numel(p_red);
        %             if nPr > 0
        %                 pp_red(1:nPr,col) = -p_red;
        %                 pp_red_idx(1:nPr,col) = idx_mask(loc_red);
        %             end
        %         end
        % 
        %         % --- Linear fits ---
        %         validOx = ~isnan(pp_ox(:,col));
        %         if any(validOx)
        %             xOx = V(pp_ox_idx(validOx,col));
        %             yOx = pp_ox(validOx,col);
        %             if numel(xOx) >= 2 && range(xOx) > 0
        %                 P_ox(:,col) = polyfit(xOx,yOx,1);
        %             end
        %         end
        % 
        %         validRed = ~isnan(pp_red(:,col));
        %         if any(validRed)
        %             xRed = V(pp_red_idx(validRed,col));
        %             yRed = pp_red(validRed,col);
        %             if numel(xRed) >= 2 && range(xRed) > 0
        %                 P_red(:,col) = polyfit(xRed,yRed,1);
        %             end
        %         end
        %     end
        % 
        %     % --- Mean slopes ---
        %     ox_slope  = mean(P_ox(1,active_cols),'omitnan'); 
        %     red_slope = mean(P_red(1,active_cols),'omitnan');
        %     disp(1/ox_slope)
        %     disp(1/red_slope)
        %     if isempty(ox_slope) || isnan(ox_slope) || ox_slope==0, ox_slope=1; end
        %     if isempty(red_slope) || isnan(red_slope) || red_slope==0, red_slope=1; end
        % 
        %     % --- Apply correction ---
        %     for k = 1:length(active_cols)
        %         col = active_cols(k);
        %         Vox  = V - ox(:,col)*ox_slope;
        %         Vred = V - red(:,col)*red_slope;
        %         E_ox_all(:,col)  = V + wEdge.*(Vox - V);
        %         E_red_all(:,col) = V + wEdge.*(Vred - V);
        %     end
        % 
        %     % --- Plot ---
        %     cla(app.UIAxes)
        %     hold(app.UIAxes,'on')
        %     for k = 1:length(active_cols)
        %         col = active_cols(k);
        %         plot(app.UIAxes,E_ox_all(:,col), ox(:,col),'LineWidth',3,'Color',[0 0 1])
        %         plot(app.UIAxes,E_red_all(:,col), red(:,col),'LineWidth',3,'Color',[1 0 0])
        %     end
        %     % plot(app.UIAxes, repmat(V,1,length(active_cols)), [red(:,active_cols), ox(:,active_cols)], ...
        %     %      'LineStyle',':','LineWidth',1,'Color',[0 0 0])
        % 
        %     % Peak markers
        %     finiteOx = ~isnan(pp_ox_idx) & ~isnan(pp_ox);
        %     finiteRed = ~isnan(pp_red_idx) & ~isnan(pp_red);
        %     if any(finiteOx(:))
        %         plot(app.UIAxes, V(pp_ox_idx(finiteOx)), pp_ox(finiteOx),'o','Color',[0 0 1])
        %     end
        %     if any(finiteRed(:))
        %         plot(app.UIAxes, V(pp_red_idx(finiteRed)), pp_red(finiteRed),'o','Color',[1 0 0])
        %     end
        %     hold(app.UIAxes,'off')
        % 
        %     % --- Store ---
        %     app.E_ox_all  = E_ox_all;
        %     app.E_red_all = E_red_all;
        %     app.hasCal    = 1;
        % 
        %     disp('Variable Rate CV Plotting Complete');
        % end


        % function Peak_corr(app)
        %     % --- Extract raw data ---
        %     V  = app.V;          % original V (N x 1 or N x S)
        %     ox = app.ox;         % N x S expected
        %     red = app.red;       % N x S expected
        %     rates_TT = cell2mat(table2array(app.RateTable.Data(:,2)));
        %     active_cols = find(rates_TT==1);
        % 
        %     if isrow(V), V = V(:); end
        %     if size(ox,1) < size(ox,2)
        %         ox = ox.'; red = red.';   % transpose if needed
        %     end
        %     [N,Sox] = size(ox);
        %     [Nred, Sred] = size(red);
        %     if N ~= Nred || Sox ~= Sred
        %         error('ox and red must have same size. ox is %dx%d, red is %dx%d', N, Sox, Nred, Sred);
        %     end
        %     S = Sox;  % number of scans
        % 
        %     % Central region mask for peak selection (avoid edges)
        %     loV = app.LowerVLimitEditField.Value;
        %     hiV = app.UpperVLimitEditField.Value;
        %     centralMask = @(v) (v >= loV + 0.10*(hiV-loV)) & (v <= hiV - 0.10*(hiV-loV));
        % 
        %     % Edge protection parameters
        %     protectFrac = 0.12; transFrac = 0.05;
        %     protectW = max(30, round(protectFrac*N));
        %     transW   = max(10, round(transFrac*N));
        %     smooth01 = @(t) (3*t.^2 - 2*t.^3);
        %     wEdge = ones(N,1);
        %     if protectW>0
        %         wEdge(1:protectW) = 0;
        %         if protectW+transW <= N
        %             t = linspace(0,1,transW).';
        %             wEdge(protectW + (1:transW)) = smooth01(t);
        %         end
        %         wEdge(end-protectW+1:end) = 0;
        %         if protectW+transW <= N
        %             t = linspace(1,0,transW).';
        %             wEdge(end-protectW-transW+1:end-protectW) = smooth01(t);
        %         end
        %     end
        % 
        %     % Preallocate. Estimate max peaks from first non-NaN column (safe)
        %     firstCol = find(~isnan(ox(:,1)),1);
        %     if isempty(firstCol)
        %         num_peaks = 5;
        %     else
        %         tmp = ox(:,1);
        %         num_peaks = max(1,length(findpeaks(tmp(~isnan(tmp)),'MinPeakProminence',0.1*range(tmp(~isnan(tmp))))));
        %     end
        % 
        %     pp_ox_idx  = nan(num_peaks,S);
        %     pp_red_idx = nan(num_peaks,S);
        %     pp_ox      = nan(num_peaks,S);
        %     pp_red     = nan(num_peaks,S);
        %     E_red_all  = nan(N,S);
        %     E_ox_all   = nan(N,S);
        % 
        %     % Fit coefficients per scan: rows = [slope; intercept], cols = scans
        %     P_ox  = nan(2,S);
        %     P_red = nan(2,S);
        % 
        %     % Peak detection and linear fitting per scan
        %     mask_global = centralMask(V);
        %     idx_mask = find(mask_global);
        % 
        %     for ii = active_cols
        %         % find first/last valid index in ox for this scan
        %         start_idx = find(~isnan(ox(:,ii)),1,'first');
        %         end_idx   = find(~isnan(ox(:,ii)),1,'last');
        % 
        %         % skip scan if no valid data
        %         if isempty(start_idx) || isempty(end_idx)
        %             continue
        %         end
        % 
        %         % clamp indices to bounds (defensive)
        %         start_idx = max(1, min(start_idx, N));
        %         end_idx   = max(1, min(end_idx, N));
        % 
        %         % safe edge assignment: only if source values exist
        %         if ~isnan(ox(start_idx,ii))
        %             red(start_idx,ii) = ox(start_idx,ii);
        %         end
        %         if ~isnan(ox(end_idx,ii))
        %             red(end_idx,ii)   = ox(end_idx,ii);
        %         end
        % 
        %         % local mask and index mapping
        %         mask = mask_global;            % use precomputed mask (same V for all scans)
        %         idx_mask = find(mask);
        % 
        %         % Oxidation peaks (ensure vector)
        %         yox = ox(mask,ii);
        %         yox = yox(:);
        %         if ~all(isnan(yox)) && numel(yox) >= 3 && range(yox(~isnan(yox))) > 0
        %             [p_ox, loc_ox] = findpeaks(yox, 'MinPeakProminence', 0.08*range(yox(~isnan(yox))));
        %             nP = numel(p_ox);
        %             if nP>0
        %                 pp_ox(1:nP,ii) = p_ox;
        %                 pp_ox_idx(1:nP,ii) = idx_mask(loc_ox);   % map to full indices
        %             end
        %         end
        % 
        %         % Reduction peaks (find minima by negation)
        %         yred = red(mask,ii);
        %         yred = yred(:);
        %         if ~all(isnan(yred)) && numel(yred) >= 3 && range(yred(~isnan(yred))) > 0
        %             [p_red, loc_red] = findpeaks(-yred, 'MinPeakProminence', 0.08*range(yred(~isnan(yred))));
        %             nPr = numel(p_red);
        %             if nPr>0
        %                 pp_red(1:nPr,ii) = -p_red;
        %                 pp_red_idx(1:nPr,ii) = idx_mask(loc_red);
        %             end
        %         end
        % 
        %         % Linear fit through oxidation peaks
        %         validOx = ~isnan(pp_ox(:,ii));
        %         if any(validOx)
        %             xOx = V(pp_ox_idx(validOx,ii));
        %             yOx = pp_ox(validOx,ii);
        %             if numel(xOx) >= 2 && range(xOx) > 0
        %                 p = polyfit(xOx, yOx, 1);
        %                 P_ox(:,ii) = p(:);   % [slope; intercept]
        %             end
        %         end
        % 
        %         % Linear fit through reduction peaks
        %         validRed = ~isnan(pp_red(:,ii));
        %         if any(validRed)
        %             xRed = V(pp_red_idx(validRed,ii));
        %             yRed = pp_red(validRed,ii);
        %             if numel(xRed) >= 2 && range(xRed) > 0
        %                 p = polyfit(xRed, yRed, 1);
        %                 P_red(:,ii) = p(:);
        %             end
        %         end
        %     end
        % 
        %     % Use mean slopes across active scans (omit NaN)
        %     ox_slope  = mean(P_ox(1,active_cols),'omitnan');
        %     red_slope = mean(P_red(1,active_cols),'omitnan');
        % 
        %     % Fallback if slopes are zero or NaN
        %     if isempty(ox_slope) || isnan(ox_slope) || ox_slope == 0
        %         ox_slope = 1;
        %     end
        %     if isempty(red_slope) || isnan(red_slope) || red_slope == 0
        %         red_slope = 1;
        %     end
        % 
        %     % Apply linear correction + edge protection (safe elementwise ops)
        %     for ii = active_cols
        %         % ensure ox(:,ii) and red(:,ii) are column vectors
        %         vox = ox(:,ii);
        %         vred = red(:,ii);
        %         if ~isvector(vox) || ~isvector(vred)
        %             vox = vox(:);
        %             vred = vred(:);
        %         end
        % 
        %         Vox = V - vox/ox_slope;
        %         Vred = V - vred/red_slope;
        %         E_ox_all(:,ii)  = V + wEdge.*(Vox - V);
        %         E_red_all(:,ii) = V + wEdge.*(Vred - V);
        %     end
        % 
        %     % --- Plotting ---
        %     cla(app.UIAxes)
        %     hold(app.UIAxes,'on')
        % 
        %     % Corrected curves
        %     for ii = active_cols
        %         plot(app.UIAxes, E_ox_all(:,ii), ox(:,ii), 'LineWidth', 3, 'Color', [0 0 1])
        %         plot(app.UIAxes, E_red_all(:,ii), red(:,ii), 'LineWidth', 3, 'Color', [1 0 0])
        %     end
        % 
        %     % Original dotted curves (stack columns)
        %     plot(app.UIAxes, repmat(V,1,length(active_cols)), [red(:,active_cols), ox(:,active_cols)], ...
        %          'LineStyle', ':', 'LineWidth', 1, 'Color', [0 0 0])
        % 
        %     % Peak markers: only finite entries
        %     finiteOx = ~isnan(pp_ox_idx) & ~isnan(pp_ox);
        %     if any(finiteOx(:))
        %         plot(app.UIAxes, V(pp_ox_idx(finiteOx)), pp_ox(finiteOx), 'o', 'Color', [0 0 1])
        %     end
        %     finiteRed = ~isnan(pp_red_idx) & ~isnan(pp_red);
        %     if any(finiteRed(:))
        %         plot(app.UIAxes, V(pp_red_idx(finiteRed)), pp_red(finiteRed), 'o', 'Color', [1 0 0])
        %     end
        % 
        %     hold(app.UIAxes,'off')
        % 
        %     % Store results in app
        %     app.E_ox_all = E_ox_all;
        %     app.E_red_all = E_red_all;
        %     app.hasCal = 1;
        % 
        %     disp('Variable Rate CV Plotting Complete');
        %     % % Extract raw data
        %     % V  = app.V;          % original V (N x 1 or N x S)
        %     % ox = app.ox;         % N x S
        %     % red = app.red;       % N x S
        %     % rates_TT = cell2mat(table2array(app.RateTable.Data(:,2)));
        %     % active_cols = find(rates_TT==1);
        %     % [N,S] = size(ox);
        %     % 
        %     % % Ensure column orientation
        %     % if size(V,1)==1, V = V(:); end
        %     % if size(ox,1)<size(ox,2), ox=ox.'; red=red.'; end
        %     % 
        %     % % Central region mask for peak selection (avoid edges)
        %     % loV = app.LowerVLimitEditField.Value;
        %     % hiV = app.UpperVLimitEditField.Value;
        %     % centralMask = @(v) (v>=loV+0.10*(hiV-loV)) & (v<=hiV-0.10*(hiV-loV));
        %     % 
        %     % % Edge protection parameters
        %     % protectFrac = 0.12; transFrac = 0.05;
        %     % protectW = max(30, round(protectFrac*N));
        %     % transW   = max(10, round(transFrac*N));
        %     % smooth01 = @(t) (3*t.^2 - 2*t.^3);
        %     % wEdge = ones(N,1);
        %     % if protectW>0
        %     %     wEdge(1:protectW) = 0;
        %     %     if protectW+transW <= N
        %     %         t = linspace(0,1,transW).';
        %     %         wEdge(protectW+(1:transW)) = smooth01(t);
        %     %     end
        %     %     wEdge(end-protectW+1:end) = 0;
        %     %     if protectW+transW <= N
        %     %         t = linspace(1,0,transW).';
        %     %         wEdge(end-protectW-transW+1:end-protectW) = smooth01(t);
        %     %     end
        %     % end
        %     % 
        %     % % Preallocate
        %     % num_peaks = max(1,length(findpeaks(ox(:,1),'MinPeakProminence',0.1*range(ox(:,1)))));
        %     % pp_ox_idx  = nan(num_peaks,S);
        %     % pp_red_idx = nan(num_peaks,S);
        %     % pp_ox      = nan(num_peaks,S);
        %     % pp_red     = nan(num_peaks,S);
        %     % E_red_all  = nan(N,S);
        %     % E_ox_all   = nan(N,S);
        %     % 
        %     % % Peak detection and linear fitting per scan
        %     % P_ox  = nan(num_peaks,2,S);
        %     % P_red = nan(num_peaks,2,S);
        %     % 
        %     % for ii = active_cols
        %     %     % Edge assignment
        %     %     start_idx = find(~isnan(ox(:,ii)),1,'first');
        %     %     end_idx   = find(~isnan(ox(:,ii)),1,'last');
        %     %     red(start_idx,ii) = ox(start_idx,ii);
        %     %     red(end_idx,ii)   = ox(end_idx,ii);
        %     % 
        %     %     mask = centralMask(V);
        %     %     idx_mask = find(mask);
        %     % 
        %     %     % oxidation peaks
        %     %     yox = ox(mask,ii);
        %     %     yox = yox(:);                       % ensure vector
        %     %     [p_ox, loc_ox] = findpeaks(yox, 'MinPeakProminence', 0.08*range(yox));
        %     %     nP = numel(p_ox);
        %     %     pp_ox(1:nP,ii) = p_ox;
        %     %     pp_ox_idx(1:nP,ii) = idx_mask(loc_ox);   % map to full indices
        %     % 
        %     %     % reduction peaks (negate to find minima)
        %     %     yred = red(mask,ii);
        %     %     yred = yred(:);
        %     %     [p_red, loc_red] = findpeaks(-yred, 'MinPeakProminence', 0.08*range(yred));
        %     %     nPr = numel(p_red);
        %     %     pp_red(1:nPr,ii) = -p_red;
        %     %     pp_red_idx(1:nPr,ii) = idx_mask(loc_red);
        %     % 
        %     %     % linear fit through peaks (use valid entries and their mapped indices)
        %     %     validOx = ~isnan(pp_ox(:,ii));
        %     %     if any(validOx)
        %     %         xOx = V(pp_ox_idx(validOx,ii));
        %     %         yOx = pp_ox(validOx,ii);
        %     %         P_ox(:,ii) = polyfit(xOx, yOx, 1);   % store slope/intercept per scan
        %     %         yfit_ox = polyval(P_ox(:,ii)', V);
        %     %     end
        %     %     validRed = ~isnan(pp_red(:,ii));
        %     %     if any(validRed)
        %     %         xRed = V(pp_red_idx(validRed,ii));
        %     %         yRed = pp_red(validRed,ii);
        %     %         P_red(:,ii) = polyfit(xRed, yRed, 1);
        %     %         yfit_red = polyval(P_red(:,ii)', V);
        %     %     end
        %     % 
        %     %     % Peak detection within central region
        %     %     % mask = centralMask(V);
        %     %     % [p, loc] = findpeaks(ox(mask,ii),'MinPeakProminence',0.08*range(ox(mask,ii)));
        %     %     % pp_ox(1:length(p),ii) = p;
        %     %     % pp_ox_idx(1:length(p),ii) = find(mask);
        %     %     % [p, loc] = findpeaks(-red(mask,ii),'MinPeakProminence',0.08*range(red(mask,ii)));
        %     %     % pp_red(1:length(p),ii) = -p;
        %     %     % pp_red_idx(1:length(p),ii) = find(mask);
        %     %     % 
        %     %     % % Linear fit through peaks
        %     %     % valid = ~isnan(pp_ox(:,ii));
        %     %     % if any(valid)
        %     %     %     P_ox(1,sum(valid),ii) = polyfit(V(pp_ox_idx(valid,ii)), pp_ox(valid,ii),1);
        %     %     %     yfit_ox = polyval(P_ox(1,:,ii), V);
        %     %     % end
        %     %     % valid = ~isnan(pp_red(:,ii));
        %     %     % if any(valid)
        %     %     %     P_red(1,sum(valid),ii) = polyfit(V(pp_red_idx(valid,ii)), pp_red(valid,ii),1);
        %     %     %     yfit_red = polyval(P_red(1,:,ii), V);
        %     %     % end
        %     % end
        %     % 
        %     % % Use mean slopes across scans
        %     % ox_slope  = mean(P_ox(:,1,active_cols),'omitnan');
        %     % red_slope = mean(P_red(:,1,active_cols),'omitnan');
        %     % 
        %     % % Apply linear correction + edge protection
        %     % for ii = active_cols
        %     %     Vox = V - ox(:,ii)/ox_slope;
        %     %     Vred = V - red(:,ii)/red_slope;
        %     %     E_ox_all(:,ii)  = V + wEdge.*(Vox - V);
        %     %     E_red_all(:,ii) = V + wEdge.*(Vred - V);
        %     % end
        %     % 
        %     % % Plotting
        %     % cla(app.UIAxes)
        %     % hold(app.UIAxes,'on')
        %     % % Corrected curves
        %     % for ii = active_cols
        %     %     plot(app.UIAxes,E_ox_all(:,ii),ox(:,ii),'LineWidth',3,'Color',[0 0 1])
        %     %     plot(app.UIAxes,E_red_all(:,ii),red(:,ii),'LineWidth',3,'Color',[1 0 0])
        %     % end
        %     % % Original dotted curves
        %     % plot(app.UIAxes, repmat(V,1,length(active_cols)), [red(:,active_cols), ox(:,active_cols)], ...
        %     %      'LineStyle',':','LineWidth',1,'Color',[0 0 0])
        %     % % after computing pp_ox_idx and pp_ox
        %     % finiteOx = ~isnan(pp_ox_idx) & ~isnan(pp_ox);
        %     % plot(app.UIAxes, V(pp_ox_idx(finiteOx)), pp_ox(finiteOx), 'o', 'Color',[0 0 1])
        %     % 
        %     % finiteRed = ~isnan(pp_red_idx) & ~isnan(pp_red);
        %     % plot(app.UIAxes, V(pp_red_idx(finiteRed)), pp_red(finiteRed), 'o', 'Color',[1 0 0])
        %     % 
        %     % % % Peak markers
        %     % % plot(app.UIAxes,V(pp_ox_idx), pp_ox,'o','Color',[0 0 1])
        %     % % plot(app.UIAxes,V(pp_red_idx), pp_red,'o','Color',[1 0 0])
        %     % % hold(app.UIAxes,'off')
        %     % 
        %     % % Store results in app
        %     % app.E_ox_all = E_ox_all;
        %     % app.E_red_all = E_red_all;
        %     % app.hasCal = 1;
        %     % 
        %     % disp('Variable Rate CV Plotting Complete');
        %     % V  = app.V;
        %     % ox = app.ox;
        %     % red = app.red;
        %     % 
        %     % % Ensure column vectors
        %     % if size(ox,1) < size(ox,2), ox=ox.'; end
        %     % if size(red,1) < size(red,2), red=red.'; end
        %     % [N,S] = size(ox);
        %     % 
        %     % % Voltage limits for central peak selection
        %     % loV = app.LowerVLimitEditField.Value;
        %     % hiV = app.UpperVLimitEditField.Value;
        %     % centralMask = @(v) (v>=loV+0.10*(hiV-loV)) & (v<=hiV-0.10*(hiV-loV));
        %     % 
        %     % % Prepare arrays to store peak info
        %     % rates_TT = cell2mat(table2array(app.RateTable.Data(:,2)));
        %     % max_peaks = max(arrayfun(@(i) length(findpeaks(ox(:,i),'MinPeakProminence',0.08*range(ox(:,i),'omitnan'))), 1:S));
        %     % 
        %     % pp_ox_idx  = nan(max_peaks, S);
        %     % pp_red_idx = nan(max_peaks, S);
        %     % pp_ox      = nan(max_peaks, S);
        %     % pp_red     = nan(max_peaks, S);
        %     % 
        %     % % === Detect peaks robustly ===
        %     % for i = 1:S
        %     %     if rates_TT(i)==1
        %     %         cen = centralMask(V(:,i));
        %     %         xi = ox(cen,i); ri = red(cen,i);
        %     % 
        %     %         if numel(xi)>=5
        %     %             [p,loc] = findpeaks(xi,'MinPeakProminence',0.08*range(xi,'omitnan'));
        %     %             if ~isempty(p)
        %     %                 [~,m] = max(abs(p));
        %     %                 pp_ox(1,i) = p(m);
        %     %                 pp_ox_idx(1,i) = find(cen,1,'first')-1 + loc(m); % map to full index
        %     %             end
        %     %         end
        %     %         if numel(ri)>=5
        %     %             [p,loc] = findpeaks(-ri,'MinPeakProminence',0.08*range(ri,'omitnan'));
        %     %             if ~isempty(p)
        %     %                 [~,m] = max(abs(p));
        %     %                 pp_red(1,i) = -p(m);
        %     %                 pp_red_idx(1,i) = find(cen,1,'first')-1 + loc(m);
        %     %             end
        %     %         end
        %     % 
        %     %         % Fix endpoints
        %     %         append_start = find(~isnan(ox(:,i)),1,'first');
        %     %         append_end   = find(~isnan(ox(:,i)),1,'last');
        %     %         red(append_start,i) = ox(append_start,i);
        %     %         red(append_end,i)   = ox(append_end,i);
        %     %     end
        %     % end
        %     % 
        %     % % === Fit linear correction for peak shift ===
        %     % P_ox = nan(1,S); P_red = nan(1,S);
        %     % for i = 1:S
        %     %     if rates_TT(i)==1 && ~isnan(pp_ox_idx(1,i)) && ~isnan(pp_red_idx(1,i))
        %     %         P_ox(i,:)  = polyfit(V(pp_ox_idx(1,i)), pp_ox(1,i), 1);
        %     %         P_red(i,:) = polyfit(V(pp_red_idx(1,i)), pp_red(1,i),1);
        %     %     end
        %     % end
        %     % ox_slope = nanmean(P_ox(:,1)); 
        %     % red_slope = nanmean(P_red(:,1));
        %     % 
        %     % % === Apply correction to all scans with edge protection ===
        %     % protectFrac = 0.12; transFrac = 0.05;
        %     % protectW = max(30, round(protectFrac*N));
        %     % transW   = max(10, round(transFrac*N));
        %     % smooth01  = @(t) (3*t.^2 - 2*t.^3);
        %     % wEdge = ones(N,1);
        %     % if protectW>0
        %     %     wEdge(1:protectW) = 0;
        %     %     if protectW+transW <= N
        %     %         t = linspace(0,1,transW).';
        %     %         wEdge(protectW+(1:transW)) = smooth01(t);
        %     %     end
        %     %     wEdge(end-protectW+1:end) = 0;
        %     %     if protectW+transW <= N
        %     %         t = linspace(1,0,transW).';
        %     %         wEdge(end-protectW-transW+1:end-protectW) = smooth01(t);
        %     %     end
        %     % end
        %     % 
        %     % E_ox_all  = nan(N,S);
        %     % E_red_all = nan(N,S);
        %     % ax = app.UIAxes; cla(ax); hold(ax,'on');
        %     % 
        %     % for i=1:S
        %     %     if rates_TT(i)==1
        %     %         Vcol = V(:); % only for per-scan edge correction
        %     %         Vred  = Vcol - red(:,i)/red_slope;
        %     %         Vox   = Vcol - ox(:,i)/ox_slope;
        %     % 
        %     %         E_red_all(:,i) = Vcol + wEdge.*(Vred - Vcol);
        %     %         E_ox_all(:,i)  = Vcol + wEdge.*(Vox - Vcol);
        %     % 
        %     %         % Vcol = V(:);           % ensure column vector
        %     %         % Vox  = Vcol - ox(:,i)/ox_slope;   % column
        %     %         % Vred = Vcol - red(:,i)/red_slope; % column
        %     %         % 
        %     %         % E_ox_all(:,i)  = Vcol + wEdge.*(Vox - Vcol);
        %     %         % E_red_all(:,i) = Vcol + wEdge.*(Vred - Vcol);
        %     % 
        %     %         plot(ax, E_ox_all(:,i), ox(:,i), 'LineWidth',3);
        %     %         plot(ax, E_red_all(:,i), red(:,i), 'LineWidth',3);
        %     %     end
        %     % end
        %     % 
        %     % % Original dotted curves for reference
        %     % active_cols = find(rates_TT==1);
        %     % 
        %     % 
        %     % 
        %     % % % For dotted original curves, keep V as-is
        %     % % active_cols = find(rates_TT==1);
        %     % % plot(ax, repmat(V,2,1), [red(:,active_cols); ox(:,active_cols)], 'LineStyle',':','LineWidth',1);
        %     % 
        %     % plot(ax, repmat(V,2,1), [red(:,active_cols); ox(:,active_cols)], 'LineStyle',':','LineWidth',1);
        %     % 
        %     % app.E_ox_all = E_ox_all;
        %     % app.E_red_all = E_red_all;
        %     % app.hasCal = 1;
        %     % hold(ax,'off');
        %     % disp('Variable Rate CV Plotting Complete');
        % end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
                dep_check = checkDependencies(app);
            if ~dep_check
                return   % Stop startup silently without throwing
            end
            app.hasCal = 0;
            axis_formatting(app, app.UIAxes)
        end

        % Callback function: BrowseDataButton, UIFigure
        function p_browse(app, event)
            [filename, filepath] = uigetfile(strcat(pwd,'/*.txt'),'Choose the ECLab Data File');
            if filename == 0
                set(app.FilepathEditField,'Value','Input Data')
            else
                disp(['User selected file: ', fullfile(filename)]);
                set(app.FilepathEditField,'Value',fullfile(filepath,filename))
            end
        end

        % Button pushed function: LoadFormattedDataButton
        function p_load(app, event)
            filename = app.FilepathEditField.Value;
            lowerlim = app.LowerVLimitEditField.Value;
            upperlim = app.UpperVLimitEditField.Value;
            
            try
                [M, voltage_col, current_col, nc_col, ~, time_col] = ECLabread(app,filename);
            catch
                warning("Please ensure an appropriate file path was chosen.")
                return
            end
            
            V = linspace(lowerlim, upperlim, 4000);
            
            cn = unique(M(:,nc_col));
            cnlength = length(cn);
            time1 = M(:,time_col);
            
            row_i = nan(cnlength+1,1);
            row_end = length(time1);
            for ii = 1:cnlength
                row_i(ii) = find(M(:,nc_col)==cn(ii),1);
            end
            row_i(cnlength+1) = row_end;
            
            red  = nan(length(V),cnlength);
            ox   = nan(length(V),cnlength);
            rate = nan(cnlength,1);
            
            for j = 1:cnlength
                a = M(row_i(j):row_i(j+1)-1,:);
                TT_cycle = a(:,nc_col)==cn(j);
                a = a(TT_cycle,:);
                time = a(:,time_col);
                v = a(:,voltage_col);
                I = a(:,current_col);
            
                [red(:,j), ox(:,j), rate(j)] = CVspline(app,v,I,V,time);
            
                appendredoxstart = find(~isnan(ox(:,j)),1,'first');
                appendredoxend   = find(~isnan(ox(:,j)),1,'last');
            
                percentage_of_points = 0.015;
                filter_modulation = 2*(floor((appendredoxend-appendredoxstart)*percentage_of_points/2))+1;
            
                v = ver;
                has_fsolve = any(strcmp({v.Name}, 'Signal Processing Toolbox'));
                if has_fsolve & filter_modulation > 1
                    ox(:,j)  = sgolayfilt(ox(:,j),1,filter_modulation);
                    red(:,j) = sgolayfilt(red(:,j),1,filter_modulation);
                end
            
                red(appendredoxstart,j) = ox(appendredoxstart,j);
                red(appendredoxend,j)   = ox(appendredoxend,j);
            end
            
            uniquerateorder = unique(rate);
            number_of_rates = length(uniquerateorder);
            
            redorder = nan(length(V),number_of_rates);
            oxorder  = nan(length(V),number_of_rates);
            fxR     = nan(length(V),number_of_rates);
            fxO     = nan(length(V),number_of_rates);
            
            idx = nan(size(uniquerateorder));
            for iii = 1:number_of_rates
                idx(iii) = find(rate==uniquerateorder(iii),1,'last');
            end
            
            for k = 1:1:number_of_rates
                redorder(:,k) = red(:,idx(k));
                oxorder(:,k)  = ox(:,idx(k));
                ratenew(k)    = rate(idx(k));
            end
            red = redorder;
            ox  = oxorder;
            
            logik = cell(number_of_rates,1);
            logik(:) = {true};
            total_list = zeros(number_of_rates,1);
            total_list(1,1) = 1;
            
            vars = {'Rate (mV/s)','Include'};
            
            app.RateTable.Data = table(uniquerateorder,logik);
            app.RateTable.ColumnName = vars;
            app.PlotontoratemVsDropDown.Items = string(uniquerateorder);
            app.PlotontoratemVsDropDown.Value = app.PlotontoratemVsDropDown.Items(1);
            app.red  = red;
            app.ox   = ox;
            app.fxR  = fxR;
            app.fxO  = fxO;
            app.V    = V;
            app.rate = ratenew;
            app.total_list = string(uniquerateorder);
            app.hasCal = 0;
            
            [~,name,ext] = fileparts(filename);
            disp(['Loaded File: ',name,ext])
            
            p_plot_CV(app)
            legend(app.UIAxes, strcat(string(app.total_list)," mV/s"), 'Location','northeast','FontSize',24, 'Box','off')
            % app.UIAxes.FontSize        = 20;
            % app.UIAxes.XLabel.FontSize = 20;
            % app.UIAxes.YLabel.FontSize = 20;
            % app.UIAxes.Title.FontSize  = 20;
            
            % ================== AXIS CLEANUP: fix crowded x-axis (keep fonts) ==================
            ax = app.UIAxes;
            
            % Limits with small padding
            x_min = lowerlim;
            x_max = upperlim;
            y_min = min([app.red(:); app.ox(:)], [], 'omitnan');
            y_max = max([app.red(:); app.ox(:)], [], 'omitnan');
            if ~isfinite(y_min) || ~isfinite(y_max) || y_min==y_max
                y_min = -1; y_max = 1;
            end
            xr = x_max - x_min; if xr<=0, xr = 1; end
            yr = y_max - y_min; if yr<=0, yr = 1; end
            
            xpad = 0.03*xr;
            ypad = 0.05*yr;
            
            xlim(ax, [x_min - xpad, x_max + xpad]);
            ylim(ax, [y_min - ypad, y_max + ypad]);
            
            axis_formatting(app, app.UIAxes)
            % Fewer x-ticks and clean formatting (fonts unchanged)
            % ax.XTick = linspace(x_min, x_max, 7);   % ~6–8 ticks is plenty
            % xtickformat(ax,'%.2f');
            % xtickangle(ax,0);
            % ax.YTickMode  = 'auto';
            % ax.XMinorTick = 'off';
            % ax.YMinorTick = 'off';
            % 
            % % Give extra room for large labels so they don't collide with the axes box
            % ax.Units    = 'normalized';
            % ax.Position = [0.12 0.20 0.74 0.72];    % [left bottom width height]
            % ================================================================================
        end

        % Cell edit callback: RateTable
        function choose_rates(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            selected_rates_mask = cell2mat(table2array(app.RateTable.Data(:,2)));
            total_selected = sum(selected_rates_mask);
            if total_selected ~= 0
    
                newlist = app.total_list(selected_rates_mask);
                app.PlotontoratemVsDropDown.Items = newlist;
    
            else
                error('At least one rate must be selected.')
            end
            
            p_plot_CV(app)
        end

        % Button pushed function: DeconvoluteCurrentButton
        function p_plot(app, event)
            V    = app.V;
            red  = app.red;
            ox   = app.ox;
            rate = app.rate;
            hasCal = app.hasCal;
            
            disp('Calculating data to plot')
            disp('hasCal:'+string(hasCal))
            
            % hasCal = isgraphics(app.UIFigure) && ...
            %          isappdata(app.UIFigure,'E_red') && isappdata(app.UIFigure,'E_ox');
            
            if hasCal
                % E_red_all = getappdata(app.UIFigure,'E_red');   % N×S
                % E_ox_all  = getappdata(app.UIFigure,'E_ox');    % N×S
                E_red_all = app.E_red_all;
                E_ox_all = app.E_ox_all;
            else
                disp("Using uncalibrated V.")
                if isvector(V), Vcol = V(:); else, Vcol = V(:,1); end
                S_all = size(ox,2);
                E_red_all = repmat(Vcol,1,S_all);   % N×S
                E_ox_all  = repmat(Vcol,1,S_all);   % N×S
            end
            
            % Table of chosen rates
            rates_TT = cell2mat(table2array(app.RateTable.Data(:,2)))';
            % Scan rate chosen for plotting
            pickrate = str2double(app.PlotontoratemVsDropDown.Value);

            rate = rate(rates_TT);
            ratepick = rate == pickrate;  

            red = red(:,rates_TT);
            ox  = ox(:,rates_TT);

            E_red = E_red_all(:,rates_TT);   % N×S_filtered
            E_ox  = E_ox_all(:,rates_TT);    % N×S_filtered
            

            for i = 1:length(ratepick)
                red(1,i)  = ox(1,i);
                red(end,i)= ox(end,i);
            end
            
            
            % Initialize matrices for individual contributions
            Npts = size(red,1);
            capacitativeRed = NaN(Npts,1);
            faradaicRed     = NaN(Npts,1);
            pseudoRed       = NaN(Npts,1);
            
            capacitativeOx  = NaN(Npts,1);
            faradaicOx      = NaN(Npts,1);
            pseudoOx        = NaN(Npts,1); 

            % transposing matrices for lsqcurvefit
            x = rate(1,:)';            
            for kk = 1:Npts
                yRed = abs(red(kk,:))';
                yOx  = abs(ox(kk,:))';
            
                if (any(isnan(yRed)) || any(isnan(yOx)))
                    disp("Data point " +string(kk)+" has a NaN value.")
                    continue
                end
            
                customFunc   = @(p, x) p(1)*x.^0.5 + p(2)*x + p(3)*x.^1.5;
                initialGuess = [0, 0, 0];
                lb = [0, 0, 0];
                ub = [Inf, Inf, Inf];
            
                options = optimoptions('lsqcurvefit', ...
                    'FunctionTolerance', 1e-6, ...
                    'StepTolerance', 1e-6, ...
                    'OptimalityTolerance', 1e-6, ...
                    'Display', 'off', ...
                    'Algorithm', 'trust-region-reflective');
                %  'trust-region-reflective' (default), 'levenberg-marquardt', and 'interior-point'.
            
                % Fit the data
                pRed = lsqcurvefit(customFunc, initialGuess, x, yRed, lb, ub, options);
                pOx  = lsqcurvefit(customFunc, initialGuess, x, yOx,  lb, ub, options);
            
                if (mod(kk,10) == 0)
                    disp('Oxidation coefficients (a, b, c):');
                    disp(pOx);
                end
            
                currentSignRed = sign(red(kk,ratepick));
                currentSignOx  = sign(ox(kk,ratepick));
            
                faradaicRed(kk)     = currentSignRed * pRed(1,1) * pickrate^0.5;
                capacitativeRed(kk) = currentSignRed * pRed(1,2) * pickrate;
                pseudoRed(kk)       = currentSignRed * pRed(1,3) * pickrate^1.5;
            
                faradaicOx(kk)      = currentSignOx * pOx(1,1) * pickrate^0.5;
                capacitativeOx(kk)  = currentSignOx * pOx(1,2) * pickrate;
                pseudoOx(kk)        = currentSignOx * pOx(1,3) * pickrate^1.5;
            
                if (mod(kk,10) == 0)
                    disp("Point "+string(kk)+" of "+string(Npts))
                end
            end
            
            V_plot = cat(1, E_red(:,ratepick), flip(E_ox(:,ratepick),1));         % 2N×1
            fx = cat(1, red(:,ratepick),   flip(ox(:,ratepick),1));          % 2N×1
            
            farad   = cat(1, faradaicRed,     flip(faradaicOx,1));
            capacit = cat(1, capacitativeRed, flip(capacitativeOx,1));
            pseudo  = cat(1, pseudoRed,       flip(pseudoOx,1));

            maxlim = max(max(ox(:,ratepick)));
            minlim = min(min(red(:,ratepick)));

            disp("V: "+string(size(V_plot)))
            disp("Fx: "+string(size(fx)))
            disp("farad: "+ string(size(farad)))
            disp("capacit: "+ string(size(capacit)))
            disp("pseudo: "+ string(size(pseudo)))

            hf = figure;
            hf.Units = 'normalized';
            hf.OuterPosition = [0.1,0.1,1,1];
            hold on
            % Total current
            h1 = plot(V_plot, fx, '.-', 'color', 'k', 'LineWidth', 3);

            % New Custom Colors (orange, blue, green)
            customHex = {'#d95f02', '#7570b3','#1b9e77'};
            customColors = zeros(3,3);

            % convert hexcodes into rgb
            for k = 1:3
                customColors(k,:) = sscanf(customHex{k}(2:end), '%2x%2x%2x', [1 3]) / 255;
            end

            fColor = customColors(1,:);
            cColor = customColors(2,:);
            pColor = customColors(3,:);
            
            % (see function SwitchValueChanged)
            if strcmp(app.Switch.Value, app.Switch.Items(1)) % Plot individual curves
                keepIndexF = ~isnan(farad);
                keepIndexC = ~isnan(capacit);
                keepIndexP = ~isnan(pseudo);
    
                % Individual Contributions (filled patches)
                patch(V_plot(keepIndexP), pseudo(keepIndexP), pColor,'FaceAlpha', 0.4, 'EdgeColor', pColor, 'LineWidth', 1.5);
                patch(V_plot(keepIndexC), capacit(keepIndexC),  cColor,'FaceAlpha', 0.4, 'EdgeColor', cColor,  'LineWidth', 1.5);
                patch(V_plot(keepIndexF), farad(keepIndexF), fColor,   'FaceAlpha', 0.4, 'EdgeColor', fColor,   'LineWidth', 1.5);
    
                disp("FaradaicRed: "+num2str(faradaicRed(1))+" End: "+num2str(faradaicRed(end)))
                disp("FaradaicOx: "+num2str(faradaicOx(1))+" End: "+num2str(faradaicRed(end)))

            else % Plot stacked area curves
                Yred = cat(2, faradaicRed, capacitativeRed, pseudoRed);
                Yox = cat(2, faradaicOx, capacitativeOx, pseudoOx);
    
                % X = repmat(app.V, size(Yred, 2), 1)'; % Vx3
                X_red = repmat(E_red(:,ratepick)', size(Yred,2),1)';
                X_ox = repmat(E_ox(:,ratepick)', size(Yox,2),1)';
                % disp(size(X_red))
                % disp(size(X_ox))

                hred = area(X_red,Yred,'FaceAlpha', 0.4, 'LineWidth', 1.5); % reduction areas
                hox = area(X_ox,Yox,'FaceAlpha', 0.4, 'LineWidth', 1.5); % oxidation areas

                % face and edge colors need to be set element wise
                for k = 1:numel(hred) % always 1-3
                    hred(k).FaceColor = customColors(k,:);
                    hred(k).EdgeColor = customColors(k,:);

                    hox(k).FaceColor = customColors(k,:);
                    hox(k).EdgeColor = customColors(k,:);
                end
            end

            % Legend with clean labels
            l1 = plot(NaN, NaN, 'LineWidth', 3, 'Color', pColor);
            l2 = plot(NaN, NaN, 'LineWidth', 3, 'Color', cColor);
            l3 = plot(NaN, NaN, 'LineWidth', 3, 'Color', fColor);

            legend([h1(1), l1, l2, l3], ...
                   'Total Current', ...
                   'Pseudocapacitive Current', ...
                   'Capacitive Current', ...
                   'Faradaic Current', ...
                   'FontSize', 24, 'FontWeight', 'normal', 'Location', 'northeast','Box','off');
        
            ax = gca;
            axis_formatting(app, ax)

            text(ax,0.1,0.05,strcat(string(pickrate), ' mV/s'), 'Units', 'normalized', ...
                'Position', [0.1 0.05], 'FontSize',24, 'HorizontalAlignment',"center",'VerticalAlignment',"middle")

            % Display Message
            if app.hasCal == 0; disp('CV Plot Completed without peak position correction'); end
            if strcmp(app.Switch.Value, app.Switch.Items(1)); disp('    using individual curves');
            else; disp('    using stacked area'); end
            disp('Deconvoluted-current CV Plot Complete')

            app.hf     = hf;
            app.pseudo = pseudo;
            app.capacit= capacit;
            app.farad  = farad;
            app.fx     = fx;
        end

        % Button pushed function: GetTableButton
        function Get_Capacitance(app, event)
            V       = app.V(:);                % N×1 potential grid
            red_all = app.red;                 % N×M
            ox_all  = app.ox;                  % N×M
            rate_all= app.rate(:);             % M×1
            capacit = app.capacit;             % either 2N×1 or N×2
            farad   = app.farad;               % either 2N×1 or N×2
            pseudo  = app.pseudo;              % either 2N×1 or N×2
        
            % Basic checks
            if isempty(V) || isempty(red_all) || isempty(ox_all) || isempty(rate_all)
                error('Deconvolution inputs not found. Run a deconvolution step first.');
            end
            if isempty(capacit) || isempty(farad) || isempty(pseudo)
                error(['Deconvolved components (capacit/farad/pseudo) are empty. ' ...
                       'Please run the deconvolution step before "Get Table".']);
            end
        
            N = numel(V);
        
            rates_TT = cell2mat(table2array(app.RateTable.Data(:,2)));  % logical mask
            if ~any(rates_TT)
                error('At least one rate must be selected in the table.');
            end
            rate_sel = rate_all(rates_TT);
            red_sel  = red_all(:,rates_TT);
            ox_sel   = ox_all(:,rates_TT);
        
            pickrate_mVs = str2double(app.PlotontoratemVsDropDown.Value); % mV/s
            if isnan(pickrate_mVs)
                error('No plot rate chosen. Pick a rate in the dropdown first.');
            end
            nu = pickrate_mVs/1000;  % V/s
            ratepick = (rate_sel == pickrate_mVs);
            if ~any(ratepick)
                error('Chosen plot rate is not among the selected rates.');
            end
        
            red = red_sel(:,ratepick);
            ox  = ox_sel(:,ratepick);
        
            function Q = safe_charge(x, y)
                m = ~isnan(x) & ~isnan(y);
                x1 = x(m); y1 = y(m);
                if isempty(x1)
                    Q = 0;
                    return
                end
                [xs, idx] = sort(x1(:), 'ascend');  % ensure increasing x for trapz
                ys = abs(y1(idx));
                Q = trapz(xs, ys) / nu;            % mA·s  (since current in mA, x in V, divide by V/s)
            end
        
            Q_red_fix = safe_charge(V, red);
            Q_ox_fix  = safe_charge(V, ox);
            Q_Total   = Q_red_fix + Q_ox_fix;
        
            function [xr, yr, xo, yo] = split_component(comp)
                sz = size(comp);
                if isvector(comp) && numel(comp) == 2*N
                    comp = comp(:);
                    yr = comp(1:N);         xr = V;            % RED half vs V
                    yo = comp(N+1:end);     xo = flip(V);      % OX half stored in reverse → use flip(V)
                elseif sz(1) == N && sz(2) >= 2
                    yr = comp(:,1);         xr = V;            % RED column vs V
                    yo = comp(:,2);         xo = V;            % OX column vs V (assume already aligned)
                else
                    error('Unexpected component shape. Expected 2N×1 or N×2.');
                end
            end
        
            [xCR, yCR, xCO, yCO] = split_component(capacit);
            [xFR, yFR, xFO, yFO] = split_component(farad);
            [xPR, yPR, xPO, yPO] = split_component(pseudo);
        
            Q_Cap_Red   = safe_charge(xCR, yCR);
            Q_Cap_Ox    = safe_charge(xCO, yCO);
            Q_Farad_Red = safe_charge(xFR, yFR);
            Q_Farad_Ox  = safe_charge(xFO, yFO);
            Q_Pseudo_Red= safe_charge(xPR, yPR);
            Q_Pseudo_Ox = safe_charge(xPO, yPO);
        
            % Totals
            Q_farad_Total   = Q_Farad_Ox   + Q_Farad_Red;
            Q_Cap_Total     = Q_Cap_Ox     + Q_Cap_Red;
            Q_pseudo_Total  = Q_Pseudo_Ox  + Q_Pseudo_Red;
        
            Q_Total_Ox      = Q_Cap_Ox + Q_Farad_Ox + Q_Pseudo_Ox;
            Q_Total_Red     = Q_Cap_Red + Q_Farad_Red + Q_Pseudo_Red;
            Q_Total_Calc    = Q_Total_Ox + Q_Total_Red;
        
            pct = @(num,den) (den~=0).*100.*num./max(den, eps);
        
            Percent_Q_Farad_Red    = pct(Q_Farad_Red,  Q_Total_Red);
            Percent_Q_Farad_Ox     = pct(Q_Farad_Ox,   Q_Total_Ox);
            Percent_Q_Farad_Total  = pct(Q_farad_Total,Q_Total_Calc);
        
            Percent_Cap_Red        = pct(Q_Cap_Red,    Q_Total_Red);
            Percent_Cap_Ox         = pct(Q_Cap_Ox,     Q_Total_Ox);
            Percent_Q_Cap_Total    = pct(Q_Cap_Total,  Q_Total_Calc);
        
            Percent_Pseduocap_Red  = pct(Q_Pseudo_Red, Q_Total_Red);
            Percent_Pseduocap_Ox   = pct(Q_Pseudo_Ox,  Q_Total_Ox);
            Percent_Q_Pseudo_Total = pct(Q_pseudo_Total,Q_Total_Calc);
        
            rows = ["Reduction"; "Oxidation"; "Total"];
            col1 = [Q_red_fix;       Q_ox_fix;        Q_Total];
            col2 = [Q_Farad_Red;     Q_Farad_Ox;      Q_farad_Total];
            col3 = [Q_Cap_Red;       Q_Cap_Ox;        Q_Cap_Total];
            col4 = [Q_Pseudo_Red;    Q_Pseudo_Ox;     Q_pseudo_Total];
            col5 = [Percent_Q_Farad_Red; Percent_Q_Farad_Ox; Percent_Q_Farad_Total];
            col6 = [Percent_Cap_Red;     Percent_Cap_Ox;     Percent_Q_Cap_Total];
            col7 = [Percent_Pseduocap_Red; Percent_Pseduocap_Ox; Percent_Q_Pseudo_Total];
        
            vars = {'Total Charge (mA·s)','Faradaic Contribution (mA·s)', ...
                    'Capacitative Contribution (mA·s)','Pseudocapacitative Contribution (mA·s)', ...
                    'Faradaic Contribution (%)','Capacitative Contribution (%)', ...
                    'Pseudocapacitative Contribution (%)'};
        
            t = table(col1,col2,col3,col4,col5,col6,col7, ...
                      'VariableNames',vars,'RowNames',rows);
            disp(t)
        
            filename = app.FilepathEditField.Value;
            [path,name,~] = fileparts(filename);
            out = fullfile(path, [name,'_Charge_Contribution_Table.xlsx']);
            writetable(t, out, 'WriteRowNames', true);
            disp([name,'_Charge_Contribution_Table.xlsx written to path: ', path])
        end

        % Value changed function: LowerVLimitEditField, 
        % ...and 1 other component
        function edit_limits(app, event)
            % p_load(app) % data doesn't need to be reloaded from the
            % file as it is stored in the app, when loading and replotting,
            % something is being done twice giving vapour trails when the
            % upper limit is lower than the data's limit
            p_plot_CV(app)
        end

        % Button pushed function: OpenDataFormatterButton
        function OpenDataFormatterButtonPushed(app, event)
            FileFormatter
        end

        % Callback function
        function PeakpositioncorrectionButtonPushed(app, event)
            % p_plot_CV_step12_edgeprotect(app)
            Peak_corr(app)
        end

        % Button pushed function: ResetPlotButton
        function ResetPlotButtonPushed(app, event)
            cla(app.UIAxes);
            delete(findall(app.UIAxes,'Tag','CVData'))
            p_plot_CV(app)

            % reset calibration, app will not use calibrated curves
            app.hasCal = 0;
        end

        % Button pushed function: SaveFigureButton
        function p_save(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Here you can make the savelocation variable to suit 
            % your computer
            hf = app.hf;
            
            filename = app.FilepathEditField.Value;
            [path,~,~]=fileparts(filename);
            
            savelocation = path;
    
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            name = app.SaveEditField.Value;
            filetype = app.SaveDropDown.Value;
            
            if filetype=='pdf'
                filenamepdf=strcat(string(name), '.pdf');
                orient(hf,'landscape')
    %                         h.f(2).PaperSize = [10, 10];
                hf.Renderer='painters'; % ensures the figure is actually saved as a vector graphic
                print(hf,filenamepdf,'-dpdf','-bestfit')
                movefile(filenamepdf,savelocation)
                disp(strcat([".pdf figure saved to path: "], savelocation))
            elseif filetype=='png'
                filenamepng=strcat(string(name), '.png');
                print(hf,filenamepng,'-dpng','-r1000')
                movefile(filenamepng,savelocation)
                disp(strcat([".png figure saved to path: "], savelocation))
            elseif filetype=='fig'
                figname=strcat(string(name), '.fig');
                saveas(hf,name)
                movefile(figname,savelocation)
                disp(strcat([".fig file saved to path: "], savelocation))
            end
        end

        % Button pushed function: PeakpositioncorrectionButton
        function PeakpositioncorrectionButtonPushed2(app, event)
            V = app.V;
            V = V(1:end);
            red = app.red;
            ox = app.ox;
            rates_TT = cell2mat(table2array(app.RateTable.Data(:,2)));
            num_peaks = length(findpeaks(ox(:,1),'MinPeakProminence',max(ox(:,1))*0.1));
            
            [N,S] = size(ox);
            % 
            % % --- Preallocate arrays ---
            % max_peaks = 10; % max peaks per scan, adjust if needed
            pp_ox     = nan(num_peaks,S);
            % pp_ox_idx = nan(num_peaks,S);
            pp_red    = nan(num_peaks,S);
            % pp_red_idx= nan(num_peaks,S);
            % 
            E_ox_all  = nan(N,S);
            E_red_all = nan(N,S);
            % 
            % P_ox      = nan(2,S);
            % P_red     = nan(2,S);
            
            pp_ox_idx = nan(num_peaks, sum(rates_TT));
            pp_red_idx = nan(num_peaks, sum(rates_TT));
            
            pp_ox = nan(num_peaks, sum(rates_TT));
            pp_red = nan(num_peaks, sum(rates_TT));
            
            V_ox_pp = nan(num_peaks, sum(rates_TT));
            V_red_pp = nan(num_peaks, sum(rates_TT));
            
            % P_ox = nan(size(pp_ox_idx,1));
            % yfit_ox = nan(size(pp_ox_idx,1));
            % P_red = nan(size(pp_ox_idx,1));
            % yfit_red = nan(size(pp_ox_idx,1));
            
            P_ox = nan(num_peaks, 2);
            P_red = nan(num_peaks, 2);
            
            k = 0;   % compact counter for selected scans
            
            for cnt = 1:length(rates_TT)
            
                if rates_TT(cnt) == 1
                    k = k + 1;
                    appendredoxstart = find(~isnan(ox(:,cnt)),1,'first');
                    appendredoxend   = find(~isnan(ox(:,cnt)),1,'last');
            
                    % --- Peak finding ---
                    [p_ox_k, locs_ox] = findpeaks(ox(:,cnt), ...
                        'MinPeakProminence', max(ox(:,cnt),[],'omitnan')*0.08);
            
                    n = min(num_peaks, numel(p_ox_k));
                    pp_ox(1:n,k)     = p_ox_k(1:n);
                    pp_ox_idx(1:n,k) = locs_ox(1:n);
                    V_ox_pp(1:n,k)   = V(locs_ox(1:n));
            
                    [p_red_k, locs_red] = findpeaks(abs(red(:,cnt)), ...
                        'MinPeakProminence', max(abs(red(:,cnt)),[],'omitnan')*0.08);
            
                    n = min(num_peaks, numel(p_red_k));
                    pp_red(1:n,k)     = -p_red_k(1:n);
                    pp_red_idx(1:n,k) = locs_red(1:n);
                    V_red_pp(1:n,k)   = V(locs_red(1:n));
                end
            end
            
            pp_red = -1.*pp_red;
            
            yfit_ox  = nan(num_peaks, numel(V));
            yfit_red = nan(num_peaks, numel(V));
            
            for i = 1:num_peaks
            
                ok = ~isnan(V_ox_pp(i,:)) & ~isnan(pp_ox(i,:));
                if nnz(ok) >= 2
                    P_ox(i,:) = polyfit(V_ox_pp(i,ok), pp_ox(i,ok), 1);
                    yfit_ox(i,:) = polyval(P_ox(i,:), V);
                else
                    P_ox(i,:) = [nan nan];
                end
            
                ok = ~isnan(V_red_pp(i,:)) & ~isnan(pp_red(i,:));
                if nnz(ok) >= 2
                    P_red(i,:) = polyfit(V_red_pp(i,ok), pp_red(i,ok), 1);
                    yfit_red(i,:) = polyval(P_red(i,:), V);
                else
                    P_red(i,:) = [nan nan];
                end
            
                disp(strcat("Ox intercept: ",string(P_ox(i,2))))
                disp(strcat("Ox slope: ",string(P_ox(i,1))))
                disp(strcat("Red intercept: ",string(P_red(i,2))))
                disp(strcat("Red slope: ", string(P_red(i,1))))
            end
            
            red_slope = mean(P_red(:,1), 'omitnan');
            ox_slope  = mean(P_ox(:,1),  'omitnan');
            
            cla(app.UIAxes)
            
            ax = app.UIAxes;                    
            hold(app.UIAxes,'on')
            % E_red_all = [];
            % E_ox_all = [];

            function y_full = complete_scan(y)
                idx = find(~isnan(y));
                if isempty(idx)
                    y_full = y;
                    return
                end
                y_full = y;
                y_full(1:idx(1)) = y(idx(1));
                y_full(idx(end):end) = y(idx(end));
            end

            for cnt = 1:length(rates_TT)
                if rates_TT(cnt) == 1
                    ii = cnt;
                    % disp(red_slope)
                    % disp(ox_slope)
                    red_full = complete_scan(red(:,ii));
                    ox_full = complete_scan(ox(:,ii));
                    
                    Vred = V' - red_full ./red_slope;
                    Vox = V' - ox_full ./ox_slope;
            
                    Vnow = [Vred; Vox];
                    y = [red(1:end,ii); ox(1:end,ii)];
                    % head(y, 10)
                    h = plot(ax,Vnow,y,'LineWidth',3,'Tag','CVData');
                    E_red_all(:,ii) = Vred;
                    E_ox_all(:,ii) = Vox;
                end
            end
            
            Vdub = [V,V]';
            y = [red(:,rates_TT==1); ox(:,rates_TT==1)];
            h1 = plot(ax,Vdub,y,'LineStyle',':','LineWidth',1,'Tag','CVData');
            h2 = plot(ax,V_ox_pp',pp_ox', 'o', 'MarkerEdgeColor', 'k');
            h3 = plot(ax,V_red_pp,pp_red, 'o', 'MarkerEdgeColor','k');
            % plot(ax, yfit_red)
            hold(app.UIAxes,'off')
            
            % ax.ColorOrder = [0 0 0; 1 0 0; 0 0 1; 0.73 0.21 0.73;...
            %        0.47 0.67 0.19; 0.85 0.33 0.10; 1 0 1; 0.64 0.08 0.18; ...
            %        .61 .51 .74; 0 1 0; 0 1 1];
            if isgraphics(app.UIFigure)
                setappdata(app.UIFigure,'E_ox',  Vox);
                setappdata(app.UIFigure,'E_red', Vred);
            end
            % ax.FontWeight = 'bold';
            % ax.LineWidth = 2;
            % ax.XLabel.String = 'Potential vs. ref (V)';
            % ax.YLabel.String = 'Current, \itI\rm/mA';
            % ax.XLim = [lowerlim-(tickspace/2) upperlim+(tickspace/2)];
            % ax.YLim = [mintick-(tickspace/2) maxtick+(tickspace/2)];
            legend(app.UIAxes, strcat(app.PlotontoratemVsDropDown.Items," mV/s"), 'Location','northeast','FontSize',24)
            axis_formatting(app, app.UIAxes)
            
            app.E_red_all = E_red_all;
            app.E_ox_all = E_ox_all;
            app.hasCal = 1;
            disp('Variable Rate CV Plotting Complete')
        end

        % Button pushed function: ClearPlotButton_2
        function ClearPlotButton_2Pushed(app, event)
            cla(app.UIAxes, 'reset');
            axis_formatting(app, app.UIAxes)

            % reset calibration, app will not use calibrated curves
            app.hasCal = 0;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if strcmp(app.Switch.Value, app.Switch.Items(1))
                disp('User selected: individual curve output plot')
            else
                disp('User selected: stacked area output plot')
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {711, 711};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @p_browse, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlotontoratemVsDropDownLabel = uilabel(app.LeftPanel);
            app.PlotontoratemVsDropDownLabel.Text = {'Plot onto '; 'rate (mV/s)'};
            app.PlotontoratemVsDropDown = uidropdown(app.LeftPanel);
            app.PlotontoratemVsDropDown.Items = {''};
            app.PlotontoratemVsDropDown.Value = '';
            app.RateTable = uitable(app.LeftPanel);
            app.RateTable.ColumnName = {'Rates (mV/s)'; 'Include?'};
            app.RateTable.RowName = {};
            app.RateTable.ColumnEditable = [false true];
            app.RateTable.CellEditCallback = createCallbackFcn(app, @choose_rates, true);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.BrowseDataButton = uibutton(app.LoadDataTab, 'push');
            app.BrowseDataButton.ButtonPushedFcn = createCallbackFcn(app, @p_browse, true);
            app.BrowseDataButton.Text = 'Browse Data';
            app.FilepathEditField = uieditfield(app.LoadDataTab, 'text');
            app.FilepathEditField.Value = 'Data File';
            app.LoadFormattedDataButton = uibutton(app.LoadDataTab, 'push');
            app.LoadFormattedDataButton.ButtonPushedFcn = createCallbackFcn(app, @p_load, true);
            app.LoadFormattedDataButton.Text = 'Load Formatted Data';
            app.LowerVLimitEditFieldLabel = uilabel(app.LoadDataTab);
            app.LowerVLimitEditFieldLabel.Text = 'Lower V Limit';
            app.LowerVLimitEditField = uieditfield(app.LoadDataTab, 'numeric');
            app.LowerVLimitEditField.ValueChangedFcn = createCallbackFcn(app, @edit_limits, true);
            app.LowerVLimitEditField.Value = -1;
            app.UpperVLimitEditFieldLabel = uilabel(app.LoadDataTab);
            app.UpperVLimitEditFieldLabel.Text = 'Upper V Limit';
            app.UpperVLimitEditField = uieditfield(app.LoadDataTab, 'numeric');
            app.UpperVLimitEditField.ValueChangedFcn = createCallbackFcn(app, @edit_limits, true);
            app.UpperVLimitEditField.Value = 1;
            app.FormatDataTab = uitab(app.TabGroup);
            app.FormatDataTab.Title = 'Format Data';
            app.OpenDataFormatterButton = uibutton(app.FormatDataTab, 'push');
            app.OpenDataFormatterButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDataFormatterButtonPushed, true);
            app.OpenDataFormatterButton.Text = 'Open Data Formatter';
            app.ClearPlotButton_2 = uibutton(app.LeftPanel, 'push');
            app.ClearPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButton_2Pushed, true);
            app.ClearPlotButton_2.Text = 'Clear Plot';
            app.ResetPlotButton = uibutton(app.LeftPanel, 'push');
            app.ResetPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPlotButtonPushed, true);
            app.ResetPlotButton.Text = 'Reset Plot';
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.UIAxes = uiaxes(app.Panel);
            app.SaveFigureButton = uibutton(app.RightPanel, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @p_save, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.GetTableButton = uibutton(app.RightPanel, 'push');
            app.GetTableButton.ButtonPushedFcn = createCallbackFcn(app, @Get_Capacitance, true);
            app.GetTableButton.Text = 'Get Table';
            app.SaveDropDown = uidropdown(app.RightPanel);
            app.SaveDropDown.Items = {'pdf', 'png', 'fig'};
            app.SaveDropDown.Value = 'pdf';
            app.SaveEditField = uieditfield(app.RightPanel, 'text');
            app.SaveEditField.Value = 'Save Deconvoluted Current Plot';
            app.DeconvoluteCurrentButton = uibutton(app.RightPanel, 'push');
            app.DeconvoluteCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @p_plot, true);
            app.DeconvoluteCurrentButton.Text = 'Deconvolute Current';
            app.PeakpositioncorrectionButton = uibutton(app.RightPanel, 'push');
            app.PeakpositioncorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @PeakpositioncorrectionButtonPushed2, true);
            app.PeakpositioncorrectionButton.Text = 'Peak position correction';
            app.PlotontoratemVsDropDownLabel_2 = uilabel(app.RightPanel);
            app.PlotontoratemVsDropDownLabel_2.Text = 'Choose Type of Output Plot:';
            app.Switch = uiswitch(app.RightPanel, 'slider');
            app.Switch.Items = {'Individual Curves', 'Stacked Area'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Individual Curves';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deConV_MATLAB_GUI_peakshift

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end